classdef CamReader_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uitoolbar1      matlab.ui.container.Toolbar
        uitoggletool1   matlab.ui.container.toolbar.ToggleTool
        uitoggletool2   matlab.ui.container.toolbar.ToggleTool
        uitoggletool3   matlab.ui.container.toolbar.ToggleTool
        uitoggletool4   matlab.ui.container.toolbar.ToggleTool
        uipanel2        matlab.ui.container.Panel
        SecOutBrowse    matlab.ui.control.Button
        SecOutFolder    matlab.ui.control.Label
        uipanel1        matlab.ui.container.Panel
        AtomNumberG     matlab.ui.control.EditField
        text13          matlab.ui.control.Label
        SatCount        matlab.ui.control.EditField
        text12          matlab.ui.control.Label
        Counting        matlab.ui.control.CheckBox
        AtomNumberP     matlab.ui.control.EditField
        text11          matlab.ui.control.Label
        CrossSection    matlab.ui.control.EditField
        text10          matlab.ui.control.Label
        text9           matlab.ui.control.Label
        PixelSize       matlab.ui.control.EditField
        Ymax            matlab.ui.control.EditField
        Ymin            matlab.ui.control.EditField
        Xmax            matlab.ui.control.EditField
        Xmin            matlab.ui.control.EditField
        text8           matlab.ui.control.Label
        text7           matlab.ui.control.Label
        ROICrop         matlab.ui.control.Button
        NameTag         matlab.ui.control.EditField
        uibuttongroup2  matlab.ui.container.ButtonGroup
        OutFolder       matlab.ui.control.Label
        OutBrowse       matlab.ui.control.Button
        uibuttongroup3  matlab.ui.container.ButtonGroup
        AutoScaleRaw    matlab.ui.control.CheckBox
        Max             matlab.ui.control.EditField
        Min             matlab.ui.control.EditField
        text5           matlab.ui.control.Label
        text4           matlab.ui.control.Label
        Custom          matlab.ui.control.RadioButton
        Rescale         matlab.ui.control.RadioButton
        Direct          matlab.ui.control.RadioButton
        Scanning        matlab.ui.control.CheckBox
        Framelist       matlab.ui.control.ListBox
        uibuttongroup1  matlab.ui.container.ButtonGroup
        Browse          matlab.ui.control.Button
        InFolder        matlab.ui.control.Label
        Scan            matlab.ui.control.StateButton
        Imagelist       matlab.ui.control.ListBox
        axes1           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function NHInp_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to NHInp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function NameHead_Callback(app, hObject, eventdata, handles)
            % hObject    handle to NameHead (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.namehead=get(handles.NHInp,'String');
            set(handles.NHDisp,'String',handles.namehead);
            guidata(hObject,handles);
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3, app.uitoggletool4];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CamReader_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to CamReader (see VARARGIN)
            
            % Choose default command line output for CamReader
            handles.output = hObject;
            YearString=datestr(now,'yyyy');
            MonthString = datestr(now,'yyyy-mm');
            DateString = datestr(now,'yyyy-mm-dd');
            handles.outfolder=['C:\',MonthString,'\',DateString];
            set(handles.OutFolder,'String',handles.outfolder);
            handles.secoutfolder=['C:\Users\BEC1\Dropbox (MIT)\BEC1\Image Data and Cicero Files\Data - Raw Images\',YearString,'\',MonthString,'\',DateString];
            set(handles.SecOutFolder,'String',handles.secoutfolder);
            ex=exist(handles.outfolder,'dir');
            if (ex~=7)
                mkdir(handles.outfolder);
            end
            
            %set up the input infolder
            handles.infolder='C:\data\Side imaging';
            set(handles.InFolder,'String',handles.infolder);
            
            handles.list={};
            handles.listshow={};
            blank=zeros(64);
            handles.img=image('Parent',handles.axes1,'CData',blank);
            setappdata(gcf,   'CurrentImage'    , 0);
            %initialize the image space in GUI
            
            
            %initialize the atom number counting parameter.
            handles.sigma=str2double(get(handles.CrossSection,'string'));
            handles.pixelsize=str2double(get(handles.PixelSize,'string'));
            handels.satcount=str2double(get(handles.SatCount,'string'));
            handles.xmin=round(str2double(get(handles.Xmin,'string')));
            handles.xmax=round(str2double(get(handles.Xmax,'string')));
            handles.ymin=round(str2double(get(handles.Ymin,'string')));
            handles.ymax=round(str2double(get(handles.Ymax,'string')));
            set(handles.Counting,'value',0);
            handles.scanning=false;
            
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Browse
        function Browse_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Browse (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            folder_name = uigetdir;
            set(handles.InFolder,'String',folder_name);
            handles.infolder=folder_name;
            guidata(hObject, handles);
        end

        % Value changed function: Framelist
        function Framelist_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Framelist (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns Framelist contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Framelist
            % var1=get(handles.Imagelist,'value');
            % var2=get(handles.Framelist,'value');
            % imgname=get(handles.Imagelist,'String');
            % num=var2-1;
            % img=fitsread([handles.outfolder,'\',imgname{var1}]);
            % if num==0
            %     frame=real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
            % else
            %     if num==4
            %         frame=real(((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
            %     else
            %         frame=img(:,:,num);
            %     end
            % end
            % set(handles.NameTag,'String',imgname{var1});
            % showimage(frame,handles);
            % guidata(hObject,handles);
            
            var1=get(handles.Imagelist,'value');
            var2=get(handles.Framelist,'value');
            imgname=get(handles.Imagelist,'String');
            img=fitsreadRL(handles.list{var1});
            setappdata(gcf,   'CurrentImage'    , img);
            num=var2-1;
            % img=fitsread([handles.outfolder,'\',imgname{var1}]);
            % if num==0
            %     frame=real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
            % else
            %     if num==4
            %         frame=real(((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
            %     else
            %         frame=img(:,:,num);
            %     end
            % end
            
            if num==0
                frame=real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
                if get(handles.AutoScaleRaw,'value')
                    set(handles.Custom,'value',1);
                end
            else
                if num==4
                    frame=real(((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
                    if get(handles.AutoScaleRaw,'value')
                        set(handles.Custom,'value',1);
                    end
                else
                    if (num==1)||(num==2)||(num==3)
                        frame=img(:,:,num);
                        if get(handles.AutoScaleRaw,'value')
                            set(handles.Rescale,'value',1);
                        end
                    else
                        set(handles.Imagelist,'value',1);
                        frame=real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
                    end
                end
            end
            
            set(handles.NameTag,'String',imgname{var1});
            showimage(frame,handles);
            
            if get(handles.Counting,'value') % do the atom counting
                %get all the initial parameter
                sigma=str2double(get(handles.CrossSection,'string'));
                pixelsize=str2double(get(handles.PixelSize,'string'));
                satcount=str2double(get(handles.SatCount,'string'));
                xmin=round(str2double(get(handles.Xmin,'string')));
                xmax=round(str2double(get(handles.Xmax,'string')));
                ymin=round(str2double(get(handles.Ymin,'string')));
                ymax=round(str2double(get(handles.Ymax,'string')));
                % crop the img
                Xr=size(img(:,:,1),2);
                Yr=size(img(:,:,1),1);
                xmin=max(1,xmin);
                xmax=min(Xr,xmax);
                ymin=max(1,ymin);
                ymax=min(Yr,ymax);
                atomcountimg=img(ymin:ymax,xmin:xmax,:);
                atomnumbermap=AtomNumber(atomcountimg,pixelsize,sigma, satcount);
                atomnumber=sum(sum(atomnumbermap));
                atomnumberG=FindAtomNumberGaussian(atomnumbermap);
                set(handles.AtomNumberG,'string',num2str(atomnumberG, '%10.3e' ));
                set(handles.AtomNumberP,'string',num2str(atomnumber, '%10.3e' ));
            end
            guidata(hObject,handles);
        end

        % Value changed function: Imagelist
        function Imagelist_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Imagelist (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns Imagelist contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Imagelist
            
            var1=get(handles.Imagelist,'value');
            var2=get(handles.Framelist,'value');
            imgname=get(handles.Imagelist,'String');
            img=fitsreadRL(handles.list{var1});
            setappdata(gcf,   'CurrentImage'    , img);
            num=var2-1;
            % img=fitsread([handles.outfolder,'\',imgname{var1}]);
            % if num==0
            %     frame=real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
            % else
            %     if num==4
            %         frame=real(((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
            %     else
            %         frame=img(:,:,num);
            %     end
            % end
            
            if num==0
                frame=real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
                if get(handles.AutoScaleRaw,'value')
                    set(handles.Custom,'value',1);
                end
            else
                if num==4
                    frame=real(((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
                    if get(handles.AutoScaleRaw,'value')
                        set(handles.Custom,'value',1);
                    end
                else
                    if (num==1)||(num==2)||(num==3)
                        frame=img(:,:,num);
                        if get(handles.AutoScaleRaw,'value')
                            set(handles.Rescale,'value',1);
                        end
                    else
                        set(handles.Imagelist,'value',1);
                        frame=real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
                    end
                end
            end
            
            
            set(handles.NameTag,'String',handles.listshow{var1});
            showimage(frame,handles);
            if get(handles.Counting,'value') % do the atom counting
                %get all the initial parameter
                sigma=str2double(get(handles.CrossSection,'string'));
                pixelsize=str2double(get(handles.PixelSize,'string'));
                satcount=str2double(get(handles.SatCount,'string'));
                xmin=round(str2double(get(handles.Xmin,'string')));
                xmax=round(str2double(get(handles.Xmax,'string')));
                ymin=round(str2double(get(handles.Ymin,'string')));
                ymax=round(str2double(get(handles.Ymax,'string')));
                % crop the img
                Xr=size(img(:,:,1),2);
                Yr=size(img(:,:,1),1);
                xmin=max(1,xmin);
                xmax=min(Xr,xmax);
                ymin=max(1,ymin);
                ymax=min(Yr,ymax);
                atomcountimg=img(ymin:ymax,xmin:xmax,:);
                atomnumbermap=AtomNumber(atomcountimg,pixelsize,sigma, satcount);
                atomnumber=sum(sum(atomnumbermap));
                atomnumberG=FindAtomNumberGaussian(atomnumbermap);
                set(handles.AtomNumberG,'string',num2str(atomnumberG, '%10.3e' ));
                set(handles.AtomNumberP,'string',num2str(atomnumber, '%10.3e' ));
            end
            guidata(hObject,handles);
        end

        % Button pushed function: OutBrowse
        function OutBrowse_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            outdir=uigetdir;
            handles.outfolder=outdir;
            set(handles.OutFolder,'String',handles.outfolder);
            guidata(hObject, handles);
        end

        % Button pushed function: ROICrop
        function ROICrop_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ROICrop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [~,rect]=imcrop(handles.axes1);
            xmin=round(rect(1));
            ymin=round(rect(2));
            xmax=round(rect(3))+xmin-1;
            ymax=round(rect(4))+ymin-1;
            set(handles.Xmin,'string',num2str(xmin));
            set(handles.Xmax,'string',num2str(xmax));
            set(handles.Ymin,'string',num2str(ymin));
            set(handles.Ymax,'string',num2str(ymax));
            
            img=getappdata(gcf,   'CurrentImage'  );
            
            if get(handles.Counting,'value') % do the atom counting
                %get all the initial parameter
                sigma=str2double(get(handles.CrossSection,'string'));
                pixelsize=str2double(get(handles.PixelSize,'string'));
                satcount=str2double(get(handles.SatCount,'string'));
                % crop the img
                Xr=size(img(:,:,1),2);
                Yr=size(img(:,:,1),1);
                xmin=max(1,xmin);
                xmax=min(Xr,xmax);
                ymin=max(1,ymin);
                ymax=min(Yr,ymax);
                atomcountimg=img(ymin:ymax,xmin:xmax,:);
                atomnumbermap=AtomNumber(atomcountimg,pixelsize,sigma, satcount);
                atomnumber=sum(sum(atomnumbermap));
                atomnumberG=FindAtomNumberGaussian(atomnumbermap);
                set(handles.AtomNumberG,'string',num2str(atomnumberG, '%10.3e' ));
                set(handles.AtomNumberP,'string',num2str(atomnumber, '%10.3e' ));
            end
            
            guidata(hObject,handles);
        end

        % Value changed function: Scan
        function Scan_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Scan (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.Scanning,'value',not(get(handles.Scanning,'value')));
            guidata(hObject,handles);
            while get(handles.Scanning,'value')
                NewFileList=dir([handles.infolder,'\*.aia']);
                N=size(NewFileList,1);
                newnamelist={};
                newnamelistshow={};
                pause(0.2);
                for i=1:N
                    temp=NewFileList(i);
                    tempname=temp.name;
                    tempname=tempname(1:end-4);
                    tempdate=datetime(tempname,'InputFormat','yyyy-MM-dd-HH;mm;ss');
                    tempnamemodified=[datestr(tempdate,'mm-dd-yyyy_HH_MM_ss'),'_top.fits'];
                    newnamelist=[[handles.outfolder,'\',tempnamemodified],newnamelist];
                    newnamelistshow=[tempnamemodified,newnamelistshow];
                    %copyfile([handles.infolder,'\',temp.name],[handles.secoutfolder]);
                    %movefile([handles.infolder,'\',temp.name],[handles.outfolder,'\',temp.name]);
                    tempimg=readaia([handles.infolder,'\',temp.name]);
                    fitswrite(tempimg,tempnamemodified);
                    copyfile(tempnamemodified,[handles.secoutfolder]);
                    movefile(tempnamemodified,[handles.outfolder,'\',tempnamemodified]);
                    delete([handles.infolder,'\',temp.name]);
                end
                handles.list=[newnamelist,handles.list];
                handles.listshow=[newnamelistshow,handles.listshow];
                if N>0
                    var2=get(handles.Framelist,'value');
                    imgname=handles.list{1};
                    imgnameshow=handles.listshow{1};
                    num=var2-1;
                    img=fitsreadRL(imgname);
                    if num==0
                        frame=real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
                        if get(handles.AutoScaleRaw,'value')
                            set(handles.Custom,'value',1);
                        end
                    else
                        if num==4
                            frame=real(((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
                            if get(handles.AutoScaleRaw,'value')
                                set(handles.Custom,'value',1);
                            end
                        else
                            if (num==1)||(num==2)||(num==3)
                                frame=img(:,:,num);
                                if get(handles.AutoScaleRaw,'value')
                                    set(handles.Rescale,'value',1);
                                end
                            else
                                set(handles.Imagelist,'value',1);
                                frame=real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
                            end
                        end
                    end
                    set(handles.NameTag,'String',imgnameshow);
                    setappdata(gcf,   'CurrentImage'    , img);
                    guidata(hObject,handles);
                    showimage(frame,handles);
                    if get(handles.Counting,'value') % do the atom counting
                        %get all the initial parameter
                        sigma=str2double(get(handles.CrossSection,'string'));
                        pixelsize=str2double(get(handles.PixelSize,'string'));
                        satcount=str2double(get(handles.SatCount,'string'));
                        xmin=round(str2double(get(handles.Xmin,'string')));
                        xmax=round(str2double(get(handles.Xmax,'string')));
                        ymin=round(str2double(get(handles.Ymin,'string')));
                        ymax=round(str2double(get(handles.Ymax,'string')));
                        % crop the img
                        Xr=size(img(:,:,1),2);
                        Yr=size(img(:,:,1),1);
                        xmin=max(1,xmin);
                        xmax=min(Xr,xmax);
                        ymin=max(1,ymin);
                        ymax=min(Yr,ymax);
                        atomcountimg=img(ymin:ymax,xmin:xmax,:);
                        atomnumbermap=AtomNumber(atomcountimg,pixelsize,sigma, satcount);
                        atomnumber=sum(sum(atomnumbermap));
                        atomnumberG=FindAtomNumberGaussian(atomnumbermap);
                        set(handles.AtomNumberG,'string',num2str(atomnumberG, '%10.3e' ));
                        set(handles.AtomNumberP,'string',num2str(atomnumber, '%10.3e' ));
                    end
                end
                set(handles.Imagelist,'String',handles.listshow);
                guidata(hObject,handles);
                pause(0.1);
            end
            guidata(hObject,handles);
        end

        % Button pushed function: SecOutBrowse
        function SecOutBrowse_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SecOutBrowse (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            secoutdir=uigetdir;
            handles.secoutfolder=secoutdir;
            set(handles.SecOutFolder,'String',handles.secoutfolder);
            guidata(hObject, handles);
            guidata(hObject,handles);
        end

        % Clicked callback: uitoggletool4
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool4.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: uitoggletool3
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable data tips based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            datacursormode(app.figure1, char(state));
        end

        % Clicked callback: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            pan(app.figure1, char(state));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CamReader';
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Pan';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'uitoggletool1_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Data Cursor';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = 'uitoggletool2_image.png';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Zoom In';
            app.uitoggletool3.ClickedCallback = createCallbackFcn(app, @uitoggletool3_ClickedCallback, true);
            app.uitoggletool3.Icon = 'uitoggletool3_image.png';
            app.uitoggletool4 = uitoggletool(app.uitoolbar1);
            app.uitoggletool4.Tag = 'uitoggletool4';
            app.uitoggletool4.Tooltip = 'Zoom Out';
            app.uitoggletool4.ClickedCallback = createCallbackFcn(app, @uitoggletool4_ClickedCallback, true);
            app.uitoggletool4.Icon = 'uitoggletool4_image.png';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.Imagelist = uilistbox(app.figure1);
            app.Imagelist.Items = {};
            app.Imagelist.ValueChangedFcn = createCallbackFcn(app, @Imagelist_Callback, true);
            app.Imagelist.Tag = 'Imagelist';
            app.Imagelist.Value = {};
            app.Scan = uibutton(app.figure1, 'state');
            app.Scan.ValueChangedFcn = createCallbackFcn(app, @Scan_Callback, true);
            app.Scan.Tag = 'Scan';
            app.Scan.Text = 'Scan';
            app.uibuttongroup1 = uibuttongroup(app.figure1);
            app.uibuttongroup1.Title = 'InputFolder';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.InFolder = uilabel(app.uibuttongroup1);
            app.InFolder.Tag = 'InFolder';
            app.InFolder.Text = 'Folder';
            app.Browse = uibutton(app.uibuttongroup1, 'push');
            app.Browse.ButtonPushedFcn = createCallbackFcn(app, @Browse_Callback, true);
            app.Browse.Tag = 'Browse';
            app.Browse.Text = 'Browse';
            app.Framelist = uilistbox(app.figure1);
            app.Framelist.Items = {'OD', 'With atom', 'Without atom', 'Dark', 'FakeOD'};
            app.Framelist.ValueChangedFcn = createCallbackFcn(app, @Framelist_Callback, true);
            app.Framelist.Tag = 'Framelist';
            app.Framelist.Value = 'OD';
            app.Scanning = uicheckbox(app.figure1);
            app.Scanning.Tag = 'Scanning';
            app.Scanning.Visible = 'off';
            app.Scanning.Text = 'Keep Scanning';
            app.uibuttongroup3 = uibuttongroup(app.figure1);
            app.uibuttongroup3.Title = 'Display controll';
            app.uibuttongroup3.Tag = 'uibuttongroup3';
            app.Direct = uiradiobutton(app.uibuttongroup3);
            app.Direct.Tag = 'Direct';
            app.Direct.Text = 'Direct';
            app.Direct.Value = true;
            app.Rescale = uiradiobutton(app.uibuttongroup3);
            app.Rescale.Tag = 'Rescale';
            app.Rescale.Text = 'Rescale';
            app.Custom = uiradiobutton(app.uibuttongroup3);
            app.Custom.Tag = 'Custom';
            app.Custom.Text = 'Custom';
            app.text4 = uilabel(app.uibuttongroup3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Min';
            app.text5 = uilabel(app.uibuttongroup3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Max';
            app.Min = uieditfield(app.uibuttongroup3, 'text');
            app.Min.Tag = 'Min';
            app.Min.Value = '0';
            app.Max = uieditfield(app.uibuttongroup3, 'text');
            app.Max.Tag = 'Max';
            app.Max.Value = '5';
            app.AutoScaleRaw = uicheckbox(app.uibuttongroup3);
            app.AutoScaleRaw.Tag = 'AutoScaleRaw';
            app.AutoScaleRaw.Text = 'Auto Scale Raw Image';
            app.AutoScaleRaw.Value = true;
            app.uibuttongroup2 = uibuttongroup(app.figure1);
            app.uibuttongroup2.Title = 'Output Folder';
            app.uibuttongroup2.Tag = 'uibuttongroup2';
            app.OutBrowse = uibutton(app.uibuttongroup2, 'push');
            app.OutBrowse.ButtonPushedFcn = createCallbackFcn(app, @OutBrowse_Callback, true);
            app.OutBrowse.Tag = 'OutBrowse';
            app.OutBrowse.Text = 'Browse';
            app.OutFolder = uilabel(app.uibuttongroup2);
            app.OutFolder.Tag = 'OutFolder';
            app.OutFolder.Text = 'Output Folder';
            app.NameTag = uieditfield(app.figure1, 'text');
            app.NameTag.Tag = 'NameTag';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Atom Counting';
            app.uipanel1.Tag = 'uipanel1';
            app.ROICrop = uibutton(app.uipanel1, 'push');
            app.ROICrop.ButtonPushedFcn = createCallbackFcn(app, @ROICrop_Callback, true);
            app.ROICrop.Tag = 'ROICrop';
            app.ROICrop.Text = 'ROI Crop';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'X Range';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Y Range';
            app.Xmin = uieditfield(app.uipanel1, 'text');
            app.Xmin.Tag = 'Xmin';
            app.Xmin.Value = '1';
            app.Xmax = uieditfield(app.uipanel1, 'text');
            app.Xmax.Tag = 'Xmax';
            app.Xmax.Value = '512';
            app.Ymin = uieditfield(app.uipanel1, 'text');
            app.Ymin.Tag = 'Ymin';
            app.Ymin.Value = '1';
            app.Ymax = uieditfield(app.uipanel1, 'text');
            app.Ymax.Tag = 'Ymax';
            app.Ymax.Value = '512';
            app.PixelSize = uieditfield(app.uipanel1, 'text');
            app.PixelSize.Tag = 'PixelSize';
            app.PixelSize.Value = '27.52';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Pixel Size (um^2)';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Cross Section(um^2)';
            app.CrossSection = uieditfield(app.uipanel1, 'text');
            app.CrossSection.Tag = 'CrossSection';
            app.CrossSection.Value = '0.215';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Atom Number(Pixel summing)';
            app.AtomNumberP = uieditfield(app.uipanel1, 'text');
            app.AtomNumberP.Tag = 'AtomNumberP';
            app.AtomNumberP.Value = '0';
            app.Counting = uicheckbox(app.uipanel1);
            app.Counting.Tag = 'Counting';
            app.Counting.Text = 'Atom Counting';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Saturation Counts';
            app.SatCount = uieditfield(app.uipanel1, 'text');
            app.SatCount.Tag = 'SatCount';
            app.SatCount.Value = '1.3357e+05';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Atom Number(Guassian Fitting)';
            app.AtomNumberG = uieditfield(app.uipanel1, 'text');
            app.AtomNumberG.Tag = 'AtomNumberG';
            app.AtomNumberG.Value = '0';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Secondary Saving Folder';
            app.uipanel2.Tag = 'uipanel2';
            app.SecOutFolder = uilabel(app.uipanel2);
            app.SecOutFolder.Tag = 'SecOutFolder';
            app.SecOutFolder.Text = 'Secondary Saving Folder';
            app.SecOutBrowse = uibutton(app.uipanel2, 'push');
            app.SecOutBrowse.ButtonPushedFcn = createCallbackFcn(app, @SecOutBrowse_Callback, true);
            app.SecOutBrowse.Tag = 'SecOutBrowse';
            app.SecOutBrowse.Text = 'Browse';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CamReader_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)CamReader_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end