classdef Image_Browser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ImageViewerPanel                matlab.ui.container.Panel
        FrametypeEditField              matlab.ui.control.EditField
        FrametypeEditFieldLabel         matlab.ui.control.Label
        ImageOptions                    matlab.ui.container.Panel
        NaCatchatomcountingPanel        matlab.ui.container.Panel
        AtomnumberEditField_2           matlab.ui.control.NumericEditField
        AtomnumberEditField_2Label      matlab.ui.control.Label
        SaturationCountsEditField_2     matlab.ui.control.NumericEditField
        SaturationCountsEditField_2Label  matlab.ui.control.Label
        CrossSectionum2EditField_2      matlab.ui.control.NumericEditField
        CrossSectionum2EditField_2Label  matlab.ui.control.Label
        PixelSizeum2EditField_2         matlab.ui.control.NumericEditField
        PixelSizeum2EditField_2Label    matlab.ui.control.Label
        DoitButton_3                    matlab.ui.control.Button
        ROICropButton_2                 matlab.ui.control.Button
        Ymax_2                          matlab.ui.control.NumericEditField
        Ymin_2                          matlab.ui.control.NumericEditField
        Xmax_2                          matlab.ui.control.NumericEditField
        Xmin_2                          matlab.ui.control.NumericEditField
        YRangeLabel_2                   matlab.ui.control.Label
        XRangeLabel_2                   matlab.ui.control.Label
        MarkingToolsPanel               matlab.ui.container.Panel
        LiatomcountingPanel             matlab.ui.container.Panel
        DoitButton_2                    matlab.ui.control.Button
        AtomnumberEditField             matlab.ui.control.NumericEditField
        AtomnumberEditFieldLabel        matlab.ui.control.Label
        SaturationCountsEditField       matlab.ui.control.NumericEditField
        SaturationCountsEditFieldLabel  matlab.ui.control.Label
        CrossSectionum2EditField        matlab.ui.control.NumericEditField
        CrossSectionum2EditFieldLabel   matlab.ui.control.Label
        PixelSizeum2EditField           matlab.ui.control.NumericEditField
        PixelSizeum2EditFieldLabel      matlab.ui.control.Label
        ROICropButton                   matlab.ui.control.Button
        Ymax                            matlab.ui.control.NumericEditField
        Ymin                            matlab.ui.control.NumericEditField
        Xmax                            matlab.ui.control.NumericEditField
        Xmin                            matlab.ui.control.NumericEditField
        YRangeLabel                     matlab.ui.control.Label
        XRangeLabel                     matlab.ui.control.Label
        NaBECatomcountingPanel          matlab.ui.container.Panel
        DoitButton                      matlab.ui.control.Button
        BECatomnumberEditField          matlab.ui.control.NumericEditField
        BECatomnumberEditFieldLabel     matlab.ui.control.Label
        AxialtrappingfreqfHzEditField   matlab.ui.control.NumericEditField
        AxialtrappingfreqfHzEditFieldLabel  matlab.ui.control.Label
        RadialtrappingfreqfHzEditField  matlab.ui.control.NumericEditField
        RadialtrappingfreqfHzEditFieldLabel  matlab.ui.control.Label
        umperpxEditField                matlab.ui.control.NumericEditField
        umperpxEditFieldLabel           matlab.ui.control.Label
        TOFmsEditField                  matlab.ui.control.NumericEditField
        TOFmsEditFieldLabel             matlab.ui.control.Label
        BECdiameterpxEditField          matlab.ui.control.NumericEditField
        BECdiameterpxEditFieldLabel     matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        FrameType                       matlab.ui.control.ListBox
        FileSelected                    matlab.ui.control.EditField
        SelectedimageLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        BrowserPanel                    matlab.ui.container.Panel
        ScanButton                      matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ImageFolder                     matlab.ui.control.EditField
        RefreshButton                   matlab.ui.control.Button
        FolderButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            path = uigetdir;
            app.ImageFolder.Value = path;

            % list all files with .fits extension
            file_list = dir(fullfile(app.ImageFolder.Value, '*.fits'));
            file_names = {file_list.name};
            file_names = file_names';

            set(app.UITable,'Data',file_names);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % list all files with .fits extension
            file_list = dir(fullfile(app.ImageFolder.Value, '*.fits'));
            file_names = {file_list.name};
            file_names = file_names';

            set(app.UITable,'Data',file_names);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            imageList = get(app.UITable,'Data');

            % set Selected image value:
            app.FileSelected.Value = imageList{indices};

            %%%%%%%%%%%%%%%%%%%%%%%%%
            % read the fits file
            img = fitsread(fullfile(app.ImageFolder.Value,app.FileSelected.Value), 'primary');
            frame_type = app.FrameType.Value;


            if frame_type == "OD"
                frame = real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));

                min = app.MinEditField.Value;
                max = app.MaxEditField.Value;
                image(frame,'Parent',app.UIAxes,'CDataMapping','scaled');
                colormap(app.UIAxes,gray(32768));
                app.UIAxes.CLim = [min, max];

            else
                if frame_type == "FakeOD"
                    frame=real(((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));

                    min = app.MinEditField.Value;
                    max = app.MaxEditField.Value;
                    image(frame,'Parent',app.UIAxes,'CDataMapping','scaled');
                    colormap(app.UIAxes,gray(32768));
                    app.UIAxes.CLim = [min, max];

                else
                    if (frame_type == "With atoms")
                        frame=img(:,:,1);
                        image(frame,'Parent',app.UIAxes,'CDataMapping','direct');
                        colormap(app.UIAxes,gray(32768));
                    elseif (frame_type == "Without atoms")
                        frame=img(:,:,2);
                        image(frame,'Parent',app.UIAxes,'CDataMapping','direct');
                        colormap(app.UIAxes,gray(32768));
                    elseif (frame_type == "Dark")
                        frame=img(:,:,3);
                        image(frame,'Parent',app.UIAxes,'CDataMapping','direct');
                        colormap(app.UIAxes,gray(32768));
                    else
                        frame=real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
                        min = app.MinEditField.Value;
                        max = app.MaxEditField.Value;
                        image(frame,'Parent',app.UIAxes,'CDataMapping','scaled');
                        colormap(app.UIAxes,gray(32768));
                        app.UIAxes.CLim = [min, max];
                    end
                end
                
            end
            set(app.UIAxes,'DataAspectRatioMode','manual','DataAspectRatio',[1 1 1],'YDir','normal') ;
            %%%%%%%%%%%%%%%%%%%%%%%%%


            % show image (EXAMPLE)
            % app.Image.ImageSource = fullfile(app.ImageFolder.Value,app.FileSelected.Value);


        end

        % Value changed function: FrameType
        function FrameTypeValueChanged(app, event)
            value = app.FrameType.Value;

            app.FrametypeEditField.Value = value;

            %%%%%%%%%%%%%%%%%%%%%%%%
            % read the fits file
            img = fitsread(fullfile(app.ImageFolder.Value,app.FileSelected.Value), 'primary');
            frame_type = app.FrameType.Value;


            if frame_type == "OD"
                frame = real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));

                min = app.MinEditField.Value;
                max = app.MaxEditField.Value;
                image(frame,'Parent',app.UIAxes,'CDataMapping','scaled');
                colormap(app.UIAxes,gray(32768));
                app.UIAxes.CLim = [min, max];


            else
                if frame_type == "FakeOD"
                    frame=real(((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));

                    min = app.MinEditField.Value;
                    max = app.MaxEditField.Value;
                    image(frame,'Parent',app.UIAxes,'CDataMapping','scaled');
                    colormap(app.UIAxes,gray(32768));
                    app.UIAxes.CLim = [min, max];

                else
                    if (frame_type == "With atoms")
                        frame=img(:,:,1);
                        image(frame,'Parent',app.UIAxes,'CDataMapping','direct');
                        colormap(app.UIAxes,gray(32768));
                    elseif (frame_type == "Without atoms")
                        frame=img(:,:,2);
                        image(frame,'Parent',app.UIAxes,'CDataMapping','direct');
                        colormap(app.UIAxes,gray(32768));
                    elseif (frame_type == "Dark")
                        frame=img(:,:,3);
                        image(frame,'Parent',app.UIAxes,'CDataMapping','direct');
                        colormap(app.UIAxes,gray(32768));
                    else
                        frame=real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
                        min = app.MinEditField.Value;
                        max = app.MaxEditField.Value;
                        image(frame,'Parent',app.UIAxes,'CDataMapping','scaled');
                        colormap(app.UIAxes,gray(32768));
                        app.UIAxes.CLim = [min, max];
                    end
                end
            end
            set(app.UIAxes,'DataAspectRatioMode','manual','DataAspectRatio',[1 1 1],'YDir','normal') ;
            %%%%%%%%%%%%%%%%%%%%%%%%

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowserPanel = uipanel(app.UIFigure);
            app.BrowserPanel.Title = 'Browser';
            app.FolderButton = uibutton(app.BrowserPanel, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Tag = 'folder_name';
            app.FolderButton.Text = 'Folder';
            app.RefreshButton = uibutton(app.BrowserPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ImageFolder = uieditfield(app.BrowserPanel, 'text');
            app.ImageFolder.Tag = 'path_name';
            app.UITable = uitable(app.BrowserPanel);
            app.UITable.ColumnName = {'FITS images:'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ScanButton = uibutton(app.BrowserPanel, 'push');
            app.ScanButton.Text = 'Scan';
            app.ImageViewerPanel = uipanel(app.UIFigure);
            app.ImageViewerPanel.Title = 'Image Viewer';
            app.UIAxes = uiaxes(app.ImageViewerPanel);
            zlabel(app.UIAxes, 'Z')
            app.SelectedimageLabel = uilabel(app.ImageViewerPanel);
            app.SelectedimageLabel.Text = 'Selected image: ';
            app.FileSelected = uieditfield(app.ImageViewerPanel, 'text');
            app.ImageOptions = uipanel(app.ImageViewerPanel);
            app.FrameType = uilistbox(app.ImageOptions);
            app.FrameType.Items = {'OD', 'With atoms', 'Without atoms', 'Dark', 'FakeOD'};
            app.FrameType.ValueChangedFcn = createCallbackFcn(app, @FrameTypeValueChanged, true);
            app.FrameType.Value = 'OD';
            app.MinEditField = uieditfield(app.ImageOptions, 'numeric');
            app.MinEditFieldLabel = uilabel(app.ImageOptions);
            app.MinEditFieldLabel.Text = 'Min';
            app.MaxEditFieldLabel = uilabel(app.ImageOptions);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.ImageOptions, 'numeric');
            app.MaxEditField.Value = 1.3;
            app.NaBECatomcountingPanel = uipanel(app.ImageOptions);
            app.NaBECatomcountingPanel.Title = 'Na BEC atom counting';
            app.BECdiameterpxEditFieldLabel = uilabel(app.NaBECatomcountingPanel);
            app.BECdiameterpxEditFieldLabel.Text = 'BEC diameter (px)';
            app.BECdiameterpxEditField = uieditfield(app.NaBECatomcountingPanel, 'numeric');
            app.BECdiameterpxEditField.Value = 70;
            app.TOFmsEditFieldLabel = uilabel(app.NaBECatomcountingPanel);
            app.TOFmsEditFieldLabel.Text = 'TOF (ms)';
            app.TOFmsEditField = uieditfield(app.NaBECatomcountingPanel, 'numeric');
            app.umperpxEditFieldLabel = uilabel(app.NaBECatomcountingPanel);
            app.umperpxEditFieldLabel.Text = 'um per px';
            app.umperpxEditField = uieditfield(app.NaBECatomcountingPanel, 'numeric');
            app.umperpxEditField.Value = 5;
            app.RadialtrappingfreqfHzEditFieldLabel = uilabel(app.NaBECatomcountingPanel);
            app.RadialtrappingfreqfHzEditFieldLabel.Text = 'Radial trapping freq (f,Hz)';
            app.RadialtrappingfreqfHzEditField = uieditfield(app.NaBECatomcountingPanel, 'numeric');
            app.RadialtrappingfreqfHzEditField.Value = 95;
            app.AxialtrappingfreqfHzEditFieldLabel = uilabel(app.NaBECatomcountingPanel);
            app.AxialtrappingfreqfHzEditFieldLabel.Text = 'Axial trapping freq (f,Hz)';
            app.AxialtrappingfreqfHzEditField = uieditfield(app.NaBECatomcountingPanel, 'numeric');
            app.AxialtrappingfreqfHzEditField.Value = 12;
            app.BECatomnumberEditFieldLabel = uilabel(app.NaBECatomcountingPanel);
            app.BECatomnumberEditFieldLabel.Text = 'BEC atom number';
            app.BECatomnumberEditField = uieditfield(app.NaBECatomcountingPanel, 'numeric');
            app.DoitButton = uibutton(app.NaBECatomcountingPanel, 'push');
            app.DoitButton.Text = 'Do it!';
            app.LiatomcountingPanel = uipanel(app.ImageOptions);
            app.LiatomcountingPanel.Title = 'Li atom counting';
            app.XRangeLabel = uilabel(app.LiatomcountingPanel);
            app.XRangeLabel.Text = 'X Range';
            app.YRangeLabel = uilabel(app.LiatomcountingPanel);
            app.YRangeLabel.Text = 'Y Range';
            app.Xmin = uieditfield(app.LiatomcountingPanel, 'numeric');
            app.Xmin.Value = 1;
            app.Xmax = uieditfield(app.LiatomcountingPanel, 'numeric');
            app.Xmax.Value = 512;
            app.Ymin = uieditfield(app.LiatomcountingPanel, 'numeric');
            app.Ymin.Value = 1;
            app.Ymax = uieditfield(app.LiatomcountingPanel, 'numeric');
            app.Ymax.Value = 512;
            app.ROICropButton = uibutton(app.LiatomcountingPanel, 'push');
            app.ROICropButton.Text = 'ROI Crop';
            app.PixelSizeum2EditFieldLabel = uilabel(app.LiatomcountingPanel);
            app.PixelSizeum2EditFieldLabel.Text = 'Pixel Size (um^2)';
            app.PixelSizeum2EditField = uieditfield(app.LiatomcountingPanel, 'numeric');
            app.PixelSizeum2EditField.Value = 27.52;
            app.CrossSectionum2EditFieldLabel = uilabel(app.LiatomcountingPanel);
            app.CrossSectionum2EditFieldLabel.Text = 'Cross Section (um^2)';
            app.CrossSectionum2EditField = uieditfield(app.LiatomcountingPanel, 'numeric');
            app.CrossSectionum2EditField.Value = 0.215;
            app.SaturationCountsEditFieldLabel = uilabel(app.LiatomcountingPanel);
            app.SaturationCountsEditFieldLabel.Text = 'Saturation Counts';
            app.SaturationCountsEditField = uieditfield(app.LiatomcountingPanel, 'numeric');
            app.SaturationCountsEditField.Value = 133570;
            app.AtomnumberEditFieldLabel = uilabel(app.LiatomcountingPanel);
            app.AtomnumberEditFieldLabel.Text = 'Atom number';
            app.AtomnumberEditField = uieditfield(app.LiatomcountingPanel, 'numeric');
            app.DoitButton_2 = uibutton(app.LiatomcountingPanel, 'push');
            app.DoitButton_2.Text = 'Do it!';
            app.MarkingToolsPanel = uipanel(app.ImageOptions);
            app.MarkingToolsPanel.Title = 'Marking Tools';
            app.NaCatchatomcountingPanel = uipanel(app.ImageOptions);
            app.NaCatchatomcountingPanel.Title = 'Na Catch atom counting';
            app.XRangeLabel_2 = uilabel(app.NaCatchatomcountingPanel);
            app.XRangeLabel_2.Text = 'X Range';
            app.YRangeLabel_2 = uilabel(app.NaCatchatomcountingPanel);
            app.YRangeLabel_2.Text = 'Y Range';
            app.Xmin_2 = uieditfield(app.NaCatchatomcountingPanel, 'numeric');
            app.Xmin_2.Value = 1;
            app.Xmax_2 = uieditfield(app.NaCatchatomcountingPanel, 'numeric');
            app.Xmax_2.Value = 512;
            app.Ymin_2 = uieditfield(app.NaCatchatomcountingPanel, 'numeric');
            app.Ymin_2.Value = 1;
            app.Ymax_2 = uieditfield(app.NaCatchatomcountingPanel, 'numeric');
            app.Ymax_2.Value = 512;
            app.ROICropButton_2 = uibutton(app.NaCatchatomcountingPanel, 'push');
            app.ROICropButton_2.Text = 'ROI Crop';
            app.DoitButton_3 = uibutton(app.NaCatchatomcountingPanel, 'push');
            app.DoitButton_3.Text = 'Do it!';
            app.PixelSizeum2EditField_2Label = uilabel(app.NaCatchatomcountingPanel);
            app.PixelSizeum2EditField_2Label.Text = 'Pixel Size (um^2)';
            app.PixelSizeum2EditField_2 = uieditfield(app.NaCatchatomcountingPanel, 'numeric');
            app.CrossSectionum2EditField_2Label = uilabel(app.NaCatchatomcountingPanel);
            app.CrossSectionum2EditField_2Label.Text = 'Cross Section (um^2)';
            app.CrossSectionum2EditField_2 = uieditfield(app.NaCatchatomcountingPanel, 'numeric');
            app.SaturationCountsEditField_2Label = uilabel(app.NaCatchatomcountingPanel);
            app.SaturationCountsEditField_2Label.Text = 'Saturation Counts';
            app.SaturationCountsEditField_2 = uieditfield(app.NaCatchatomcountingPanel, 'numeric');
            app.AtomnumberEditField_2Label = uilabel(app.NaCatchatomcountingPanel);
            app.AtomnumberEditField_2Label.Text = 'Atom number';
            app.AtomnumberEditField_2 = uieditfield(app.NaCatchatomcountingPanel, 'numeric');
            app.FrametypeEditFieldLabel = uilabel(app.ImageViewerPanel);
            app.FrametypeEditFieldLabel.Text = 'Frame type:';
            app.FrametypeEditField = uieditfield(app.ImageViewerPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Browser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end