classdef Image_Acquisition_BEC1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MarkingToolPanel           matlab.ui.container.Panel
        ClearButton                matlab.ui.control.Button
        ShowCheckBox               matlab.ui.control.CheckBox
        SaveMarkDir                matlab.ui.control.EditField
        SavemarkButton             matlab.ui.control.Button
        LoadMarkFile               matlab.ui.control.EditField
        LoadmarkButton             matlab.ui.control.Button
        MarkButton                 matlab.ui.control.Button
        NaCatchpixelcountingPanel  matlab.ui.container.Panel
        CatchROILabel              matlab.ui.control.Label
        BackgroundROILabel         matlab.ui.control.Label
        ROI_Crop_Background        matlab.ui.control.Button
        Ymax_Background            matlab.ui.control.NumericEditField
        Ymin_Background            matlab.ui.control.NumericEditField
        Xmax_Background            matlab.ui.control.NumericEditField
        Xmin_Background            matlab.ui.control.NumericEditField
        YRangeLabel_2              matlab.ui.control.Label
        XRangeLabel_2              matlab.ui.control.Label
        NaCatchPxCount             matlab.ui.control.NumericEditField
        CatchscoreLabel            matlab.ui.control.Label
        NaCatchDoIt                matlab.ui.control.Button
        ROI_Crop_Na_Catch          matlab.ui.control.Button
        Ymax_Na_Catch              matlab.ui.control.NumericEditField
        Ymin_Na_Catch              matlab.ui.control.NumericEditField
        Xmax_Na_Catch              matlab.ui.control.NumericEditField
        Xmin_Na_Catch              matlab.ui.control.NumericEditField
        YRangeLabel                matlab.ui.control.Label
        XRangeLabel                matlab.ui.control.Label
        FramesPanel                matlab.ui.container.Panel
        Refresh                    matlab.ui.control.Button
        BrightnessSlider           matlab.ui.control.Slider
        BrightnessSliderLabel      matlab.ui.control.Label
        MaxEditField               matlab.ui.control.NumericEditField
        MaxEditFieldLabel          matlab.ui.control.Label
        MinEditFieldLabel          matlab.ui.control.Label
        MinEditField               matlab.ui.control.NumericEditField
        KillButton                 matlab.ui.control.Button
        FrameType                  matlab.ui.control.ListBox
        UITable                    matlab.ui.control.Table
        SaveDirectoryPanel         matlab.ui.container.Panel
        BrowseButton               matlab.ui.control.Button
        Browse                     matlab.ui.control.EditField
        AcquisitionPanel_2         matlab.ui.container.Panel
        AcquireButton              matlab.ui.control.Button
        AbortButton                matlab.ui.control.StateButton
        PreviewButton              matlab.ui.control.Button
        Exposure                   matlab.ui.control.NumericEditField
        ExposureusLabel            matlab.ui.control.Label
        DeviceSelectionPanel       matlab.ui.container.Panel
        CurrentdeviceLabel         matlab.ui.control.Label
        RefreshButton              matlab.ui.control.Button
        SelectButton               matlab.ui.control.Button
        ListBox                    matlab.ui.control.ListBox
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        namelist = []; 
        devlist = [];
        devicelist = [];
        currentDevice = NaN;
        currentDeviceName = '';
        fullDev = NaN;
        mark = NaN;
        x_mark_data = [];
        y_mark_data = [];
        markplot = 0;
        FileSelected = 0; 
        XMIN = 0;
        XMAX = 0;
        YMIN = 0;
        YMAX = 0;
        oldSizeX = 0;
        oldSizeY = 0;
        src = 0;
        vid = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            imaqreset
            %refresh the device list
            app.devicelist = SearchDevice();
            app.namelist = app.devicelist(:,3);
            app.devlist = app.devicelist(:,3);

            for i=1:length(app.namelist)
                if strcmp(app.namelist{i},'AVT Guppy PRO F503C-gentl1')
                    app.devlist{i}= 'Top Green';
                elseif strcmp(app.namelist{i},'AVT Guppy PRO F503B-gentl2')
                    app.devlist{i}='Side Green';
                elseif strcmp(app.namelist{i}, 'AVT Guppy PRO F503B-gentl3')
                    app.devlist{i} = 'MOT';
                else
                    app.devlist{i}= app.namelist{i};
                end
            end

            set(app.ListBox,'Items', app.devlist);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            %refresh the device list
            app.devicelist = SearchDevice();
            app.namelist = app.devicelist(:,3);
            app.devlist = app.devicelist(:,3);

            for i=1:length(app.namelist)
                if strcmp(app.namelist{i},'AVT Guppy PRO F503C-gentl1')
                    app.devlist{i}= 'Top Green';
                elseif strcmp(app.namelist{i},'AVT Guppy PRO F503B-gentl2')
                    app.devlist{i}='Side Green';
                elseif strcmp(app.namelist{i}, 'AVT Guppy PRO F503B-gentl3')
                    app.devlist{i} = 'MOT';
                else
                    app.devlist{i}= app.namelist{i};
                end
            end

            set(app.ListBox,'Items', app.devlist);

            %disp(devicelist);

        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            devName = app.ListBox.Value;
            selectedIndex = 0;

            for i=1:length(app.namelist)
                if strcmp(app.devlist{i},'MOT')
                    selectedIndex = i;
                elseif strcmp(app.devlist{i}, 'Side Green')
                    selectedIndex = i;
                elseif strcmp(app.devlist{i}, 'Top Green')
                    selectedIndex = i;
                end
            end

            if strcmp(devName, 'Top Green')
                app.currentDeviceName = 'AVT Guppy PRO F503C-gentl1';
            elseif strcmp(devName, 'Side Green')
                app.currentDeviceName = 'AVT Guppy PRO F503B-gentl2';
            elseif strcmp(devName, 'MOT')
                app.currentDeviceName = 'AVT Guppy PRO F503B-gentl3';
            else
                disp('Error');
            end

            app.CurrentdeviceLabel.Text = ['Current device: ', app.currentDeviceName];

            % read out guppy settings

            app.fullDev = app.devicelist(selectedIndex,:);
            adaptor = app.fullDev{1};
            id = app.fullDev{2};

            app.currentDevice = videoinput(adaptor,id);
            name = imaqhwinfo(app.currentDevice,'DeviceName');

            app.src = getselectedsource(app.currentDevice);
            if contains(name,'Guppy')
                app.Exposure.Value = app.src.ExposureTime;
            end

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir;
            app.Browse.Value = path;

            if not(isempty(path))
                % list all files with .fits extension
                file_list = dir(fullfile(app.Browse.Value, '*.fits'));
                file_names = {file_list.name};
                file_names = file_names';
                file_names = flip(file_names);

                set(app.UITable,'Data',file_names);
                entries = length(file_names);
                app.UITable.ColumnName = ['FITS images: ', num2str(entries)];

                app.KillButton.Enable = 'on';
                app.MaxEditField.Enable = 'on';
                app.MinEditField.Enable = 'on';
                app.MaxEditFieldLabel.Enable = 'on';
                app.MinEditFieldLabel.Enable = 'on';
                app.BrightnessSlider.Enable = 'on';
                app.FrameType.Enable = 'on';

                app.AcquireButton.Enable = 'on';
                app.PreviewButton.Enable = 'on';
            end

        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            
            app.vid = app.currentDevice;
            app.src = getselectedsource(app.vid);

            % stop running if running:
            stop(app.vid)

            triggerconfig(app.vid, 'immediate', 'none', 'none');
            app.src.IIDCMode = 'Mode6';
            
            disp(app.src)
            disp(app.vid)

            if app.currentDevice~=0
                preview(app.vid);
            end
        end

        % Button pushed function: ROI_Crop_Background
        function ROI_Crop_BackgroundButtonPushed(app, event)
            [~,rect]=imcrop(app.UIAxes);
            xminBG=round(rect(1));
            yminBG=round(rect(2));
            xmaxBG=round(rect(3))+xminBG-1;
            ymaxBG=round(rect(4))+yminBG-1;

            app.Xmin_Background.Value = xminBG;
            app.Xmax_Background.Value = xmaxBG;
            app.Ymin_Background.Value = yminBG;
            app.Ymax_Background.Value = ymaxBG; 
        end

        % Button pushed function: ROI_Crop_Na_Catch
        function ROI_Crop_Na_CatchButtonPushed(app, event)
            [~,rect]=imcrop(app.UIAxes);
            xmin=round(rect(1));
            ymin=round(rect(2));
            xmax=round(rect(3))+xmin-1;
            ymax=round(rect(4))+ymin-1;

            app.Xmin_Na_Catch.Value = xmin;
            app.Xmax_Na_Catch.Value = xmax;
            app.Ymin_Na_Catch.Value = ymin;
            app.Ymax_Na_Catch.Value = ymax;
        end

        % Button pushed function: NaCatchDoIt
        function NaCatchDoItButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%
            % read the fits file
            img = fitsread(fullfile(app.Browse.Value,app.FileSelected), 'primary');

            %initialize the atom number counting parameter.
            xmin = app.Xmin_Na_Catch.Value;
            xmax = app.Xmax_Na_Catch.Value;
            ymin = app.Ymin_Na_Catch.Value;
            ymax = app.Ymax_Na_Catch.Value;


            % initialize background counting params
            xminBG = app.Xmin_Background.Value;
            xmaxBG = app.Xmax_Background.Value;
            yminBG = app.Ymin_Background.Value;
            ymaxBG = app.Ymax_Background.Value;

            % crop the img
            Xr=size(img(:,:,1),2);
            Yr=size(img(:,:,1),1);
            xmin=max(1,xmin);
            xmax=min(Xr,xmax);
            ymin=max(1,ymin);
            ymax=min(Yr,ymax);

            % crop the img for background
            Xrr=size(img(:,:,1),2);
            Yrr=size(img(:,:,1),1);
            xminBG=max(1,xminBG);
            xmaxBG=min(Xrr,xmaxBG);
            yminBG=max(1,yminBG);
            ymaxBG=min(Yrr,ymaxBG);

            % compute areas:
            BG_area = (ymaxBG-yminBG)*(xmaxBG - xminBG);
            Catch_area = (ymax-ymin)*(xmax-xmin);

            % for the mag trap
            ct_img = img(ymin:ymax,xmin:xmax,:);
            pixel_OD_number = real(-log((ct_img(:,:,1)-ct_img(:,:,3))./(ct_img(:,:,2)-ct_img(:,:,3))));

            % for the background
            bg_img = img(yminBG:ymaxBG,xminBG:xmaxBG,:);
            pixel_OD_number_BG = real(-log((bg_img(:,:,1)-bg_img(:,:,3))./(bg_img(:,:,2)-bg_img(:,:,3))));

            % turn nan and inf to 0 so that they don't count
            pixel_OD_number(isinf(pixel_OD_number)) = 0;
            pixel_OD_number(isnan(pixel_OD_number)) = 0;

            pixel_OD_number_BG(isinf(pixel_OD_number_BG)) = 0;
            pixel_OD_number_BG(isnan(pixel_OD_number_BG)) = 0;

            % take sums...
            NaCatchPxCount_WO_BG = sum(sum(pixel_OD_number));
            BG = sum(sum(pixel_OD_number_BG));

            % convert BG into total BG under catch ROI:
            BG = round(Catch_area*(BG/BG_area));
            
            % final score = catch - bg
            app.NaCatchPxCount.Value = NaCatchPxCount_WO_BG-BG;

        end

        % Button pushed function: MarkButton
        function MarkButtonPushed(app, event)
            app.mark = drawfreehand(app.UIAxes, 'FaceAlpha', 0);

            xy = app.mark.Position;
            app.x_mark_data = xy(:, 1);
            app.y_mark_data = xy(:, 2);

            hold(app.UIAxes, 'on'); % Don't blow away the image, then plot mark
            app.markplot = plot(app.x_mark_data, app.y_mark_data, 'LineWidth', 2,'Color','green','Parent', app.UIAxes);

            % once done, disable the button:
            app.MarkButton.Enable = 'off';
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            if app.markplot ~= 0
                app.UIAxes;
                delete(app.markplot);
                app.x_mark_data = [];
                app.y_mark_data = [];
                app.markplot = 0;
                app.mark.delete();
                display_image(app);

                % after clearing, enable mark button again:
                app.MarkButton.Enable = 'on';

            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            imageList = get(app.UITable,'Data');
            app.KillButton.Enable = 'on';
            % set Selected image value:
            app.FileSelected = imageList{indices};
            
            % display image
            display_image(app);
        end

        % Value changed function: FrameType
        function FrameTypeValueChanged(app, event)
            value = app.FrameType.Value;
            % display image
            display_image(app);
        end

        % Callback function
        function AcquirerepeatedlyButtonPushed(app, event)
            
        end

        % Callback function
        function AbortButtonPushed(app, event)

        end

        % Value changed function: AbortButton
        function AbortButtonValueChanged(app, event)
            app.src = 0;
            app.vid = 0;
            disp('Abort');
            app.PreviewButton.Enable = 'on';
            app.AcquireButton.Enable = 'on';
        end

        % Button pushed function: KillButton
        function KillButtonPushed(app, event)
            selected_file_name = app.FileSelected;
            fullpath = fullfile(app.Browse.Value,app.FileSelected);
 
            if length(selected_file_name) >= 1 % if some file is selected
                delete(fullpath);
            end

            % refresh now
            file_list = dir(fullfile(app.Browse.Value, '*.fits'));
            file_names = {file_list.name};
            file_names = file_names';
            file_names = flip(file_names);

            set(app.UITable,'Data',file_names);
            entries = length(file_names);
            app.UITable.ColumnName = ['FITS images: ', num2str(entries)];
        end

        % Value changed function: Exposure
        function ExposureValueChanged(app, event)
            app.vid = app.currentDevice;
            if (app.vid~=0) 
                app.src = getselectedsource(app.vid);
                app.src.ExposureAuto = 'Off';
                app.src.ExposureTime = app.Exposure.Value;
            end
        end

        % Button pushed function: Refresh
        function RefreshButtonPushed2(app, event)
            % refresh now
            file_list = dir(fullfile(app.Browse.Value, '*.fits'));
            file_names = {file_list.name};
            file_names = file_names';
            file_names = flip(file_names);

            set(app.UITable,'Data',file_names);
            entries = length(file_names);
            app.UITable.ColumnName = ['FITS images: ', num2str(entries)];
        end

        % Button pushed function: AcquireButton
        function AcquireButtonPushed(app, event)

            app.AcquireButton.Enable = 'off';

            % options
            app.PreviewButton.Enable = 'off';
            app.AbortButton.Enable = 'on';

            % set the device
            app.vid = app.currentDevice;

            % end preview:
            closepreview(app.vid)
            app.src = getselectedsource(app.currentDevice);
            app.src.ExposureAuto = 'Off';

            
            if (app.vid~=0) 
                while app.AbortButton.Value == 0
                    % set cam params 
                    app.src = getselectedsource(app.vid);
                    triggerconfig(app.vid, 'hardware', 'DeviceSpecific', 'DeviceSpecific');
                    app.src.TriggerActivation = 'RisingEdge';
                    app.src.TriggerMode = 'On';
                    app.src.ExposureAuto = 'Off';
                    app.src.IIDCMode = 'Mode6';
                    %src.AcquisitionFrameRate = 21.7984;
                    %src.IIDCFreeBandwidth = 9830;

                    app.vid.TriggerRepeat = 3;
                    app.vid.FramesPerTrigger = 1;
                    app.vid.Timeout=1000000;

                    roi = app.vid.ROI;
                    height = roi(4);
                    width = roi(3);
                    start(app.vid);

                    % take 3 images: WA, WO, Dark
                    counter = 0;
                    tempimage = cell(1,3);
                    while (counter < 3) 
                        tempimage{counter+1} = getsnapshot(app.vid);
                        counter = counter+1;
                    end
                    stop(app.vid);
  
                    imag = zeros(height,width,3,'uint8');
                    for i=1:3
                        imag(:,:,i) = tempimage{i};
                    end

                    %save the picture to hard disk if indicated
                    time = datestr(now,'yyyy-mm-dd_HH-MM-SS');
                    filename=[app.Browse.Value,'\',time,'_catch.fits'];
                    fitswrite(imag, filename);

                    % refresh now
                    file_list = dir(fullfile(app.Browse.Value, '*.fits'));
                    file_names = {file_list.name};
                    file_names = file_names';
                    file_names = flip(file_names);

                    set(app.UITable,'Data',file_names);
                    entries = length(file_names);
                    app.UITable.ColumnName = ['FITS images: ', num2str(entries)];

                    disp('Acquiring...')
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.DeviceSelectionPanel = uipanel(app.UIFigure);
            app.DeviceSelectionPanel.Title = 'Device Selection';
            app.ListBox = uilistbox(app.DeviceSelectionPanel);
            app.ListBox.Items = {'Guppy 1', 'Guppy 2', 'Guppy 3'};
            app.ListBox.Value = 'Guppy 1';
            app.SelectButton = uibutton(app.DeviceSelectionPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.RefreshButton = uibutton(app.DeviceSelectionPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.CurrentdeviceLabel = uilabel(app.DeviceSelectionPanel);
            app.CurrentdeviceLabel.Text = 'Current device:';
            app.AcquisitionPanel_2 = uipanel(app.UIFigure);
            app.AcquisitionPanel_2.Title = 'Acquisition';
            app.ExposureusLabel = uilabel(app.AcquisitionPanel_2);
            app.ExposureusLabel.Text = 'Exposure (us): ';
            app.Exposure = uieditfield(app.AcquisitionPanel_2, 'numeric');
            app.Exposure.ValueChangedFcn = createCallbackFcn(app, @ExposureValueChanged, true);
            app.Exposure.Value = 1;
            app.PreviewButton = uibutton(app.AcquisitionPanel_2, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Enable = 'off';
            app.PreviewButton.Text = 'Preview';
            app.AbortButton = uibutton(app.AcquisitionPanel_2, 'state');
            app.AbortButton.ValueChangedFcn = createCallbackFcn(app, @AbortButtonValueChanged, true);
            app.AbortButton.Enable = 'off';
            app.AbortButton.Text = 'Abort';
            app.AcquireButton = uibutton(app.AcquisitionPanel_2, 'push');
            app.AcquireButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireButtonPushed, true);
            app.AcquireButton.Text = 'Acquire';
            app.SaveDirectoryPanel = uipanel(app.UIFigure);
            app.SaveDirectoryPanel.Title = 'Save Directory';
            app.Browse = uieditfield(app.SaveDirectoryPanel, 'text');
            app.BrowseButton = uibutton(app.SaveDirectoryPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FramesPanel = uipanel(app.UIFigure);
            app.FramesPanel.Title = 'Frames';
            app.UITable = uitable(app.FramesPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.FrameType = uilistbox(app.FramesPanel);
            app.FrameType.Items = {'OD', 'With atoms', 'Without atoms', 'Dark', 'FakeOD'};
            app.FrameType.ValueChangedFcn = createCallbackFcn(app, @FrameTypeValueChanged, true);
            app.FrameType.Enable = 'off';
            app.FrameType.Value = 'OD';
            app.KillButton = uibutton(app.FramesPanel, 'push');
            app.KillButton.ButtonPushedFcn = createCallbackFcn(app, @KillButtonPushed, true);
            app.KillButton.Enable = 'off';
            app.KillButton.Text = 'Delete';
            app.MinEditField = uieditfield(app.FramesPanel, 'numeric');
            app.MinEditFieldLabel = uilabel(app.FramesPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MaxEditFieldLabel = uilabel(app.FramesPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.FramesPanel, 'numeric');
            app.MaxEditField.Value = 4;
            app.BrightnessSliderLabel = uilabel(app.FramesPanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.FramesPanel);
            app.BrightnessSlider.Limits = [10 16];
            app.BrightnessSlider.MinorTicks = [9 11 13 15];
            app.BrightnessSlider.Value = 14;
            app.Refresh = uibutton(app.FramesPanel, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed2, true);
            app.Refresh.Text = 'Refresh';
            app.NaCatchpixelcountingPanel = uipanel(app.UIFigure);
            app.NaCatchpixelcountingPanel.Title = 'Na Catch pixel counting';
            app.XRangeLabel = uilabel(app.NaCatchpixelcountingPanel);
            app.XRangeLabel.Text = 'X Range';
            app.YRangeLabel = uilabel(app.NaCatchpixelcountingPanel);
            app.YRangeLabel.Text = 'Y Range';
            app.Xmin_Na_Catch = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Xmin_Na_Catch.Value = 1;
            app.Xmax_Na_Catch = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Xmax_Na_Catch.Value = 512;
            app.Ymin_Na_Catch = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Ymin_Na_Catch.Value = 1;
            app.Ymax_Na_Catch = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Ymax_Na_Catch.Value = 512;
            app.ROI_Crop_Na_Catch = uibutton(app.NaCatchpixelcountingPanel, 'push');
            app.ROI_Crop_Na_Catch.ButtonPushedFcn = createCallbackFcn(app, @ROI_Crop_Na_CatchButtonPushed, true);
            app.ROI_Crop_Na_Catch.Text = 'ROI Crop';
            app.NaCatchDoIt = uibutton(app.NaCatchpixelcountingPanel, 'push');
            app.NaCatchDoIt.ButtonPushedFcn = createCallbackFcn(app, @NaCatchDoItButtonPushed, true);
            app.NaCatchDoIt.Text = 'Do it!';
            app.CatchscoreLabel = uilabel(app.NaCatchpixelcountingPanel);
            app.CatchscoreLabel.Text = 'Catch score';
            app.NaCatchPxCount = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.XRangeLabel_2 = uilabel(app.NaCatchpixelcountingPanel);
            app.XRangeLabel_2.Text = 'X Range';
            app.YRangeLabel_2 = uilabel(app.NaCatchpixelcountingPanel);
            app.YRangeLabel_2.Text = 'Y Range';
            app.Xmin_Background = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Xmin_Background.Value = 1;
            app.Xmax_Background = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Xmax_Background.Value = 512;
            app.Ymin_Background = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Ymin_Background.Value = 1;
            app.Ymax_Background = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Ymax_Background.Value = 512;
            app.ROI_Crop_Background = uibutton(app.NaCatchpixelcountingPanel, 'push');
            app.ROI_Crop_Background.ButtonPushedFcn = createCallbackFcn(app, @ROI_Crop_BackgroundButtonPushed, true);
            app.ROI_Crop_Background.Text = 'ROI Crop';
            app.BackgroundROILabel = uilabel(app.NaCatchpixelcountingPanel);
            app.BackgroundROILabel.Text = 'Background ROI:';
            app.CatchROILabel = uilabel(app.NaCatchpixelcountingPanel);
            app.CatchROILabel.Text = 'Catch ROI:';
            app.MarkingToolPanel = uipanel(app.UIFigure);
            app.MarkingToolPanel.Title = 'Marking Tool';
            app.MarkButton = uibutton(app.MarkingToolPanel, 'push');
            app.MarkButton.ButtonPushedFcn = createCallbackFcn(app, @MarkButtonPushed, true);
            app.MarkButton.Text = 'Mark';
            app.LoadmarkButton = uibutton(app.MarkingToolPanel, 'push');
            app.LoadmarkButton.Text = 'Load mark';
            app.LoadMarkFile = uieditfield(app.MarkingToolPanel, 'text');
            app.SavemarkButton = uibutton(app.MarkingToolPanel, 'push');
            app.SavemarkButton.Text = 'Save mark';
            app.SaveMarkDir = uieditfield(app.MarkingToolPanel, 'text');
            app.ShowCheckBox = uicheckbox(app.MarkingToolPanel);
            app.ShowCheckBox.Text = 'Show';
            app.ShowCheckBox.Value = true;
            app.ClearButton = uibutton(app.MarkingToolPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Acquisition_BEC1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end