classdef Image_Browser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ToolsTab                        matlab.ui.container.Tab
        LiveplottingPanel               matlab.ui.container.Panel
        UndoPlot                        matlab.ui.control.Button
        SumPlotting                     matlab.ui.control.Button
        PlottingVariable                matlab.ui.control.EditField
        LiveplotCheckBox                matlab.ui.control.CheckBox
        ROI_Crop_Plot                   matlab.ui.control.Button
        AddPlottingVariable             matlab.ui.control.Button
        ClearPlot                       matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        LightPanel                      matlab.ui.container.Panel
        BrightnessSlider                matlab.ui.control.Slider
        BrightnessSliderLabel           matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        FrameType                       matlab.ui.control.ListBox
        LiLFatomcountingPanel           matlab.ui.container.Panel
        LiAtomCounting                  matlab.ui.control.Button
        LiAtomNumber                    matlab.ui.control.NumericEditField
        AtomnumberEditFieldLabel        matlab.ui.control.Label
        SatCount                        matlab.ui.control.NumericEditField
        SaturationCountsEditFieldLabel  matlab.ui.control.Label
        CrossSection                    matlab.ui.control.NumericEditField
        CrossSectionum2EditFieldLabel   matlab.ui.control.Label
        PixelSize                       matlab.ui.control.NumericEditField
        PixelSizeum2EditFieldLabel      matlab.ui.control.Label
        ROI_Crop_Li                     matlab.ui.control.Button
        YmaxLi                          matlab.ui.control.NumericEditField
        YminLi                          matlab.ui.control.NumericEditField
        XmaxLi                          matlab.ui.control.NumericEditField
        XminLi                          matlab.ui.control.NumericEditField
        YRangeLabel                     matlab.ui.control.Label
        XRangeLabel                     matlab.ui.control.Label
        NaCatchpixelcountingPanel       matlab.ui.container.Panel
        CatchROILabel                   matlab.ui.control.Label
        BackgroundROILabel              matlab.ui.control.Label
        ROI_Crop_Background             matlab.ui.control.Button
        Ymax_Background                 matlab.ui.control.NumericEditField
        Ymin_Background                 matlab.ui.control.NumericEditField
        Xmax_Background                 matlab.ui.control.NumericEditField
        Xmin_Background                 matlab.ui.control.NumericEditField
        YRangeLabel_3                   matlab.ui.control.Label
        XRangeLabel_3                   matlab.ui.control.Label
        NaCatchPxCount                  matlab.ui.control.NumericEditField
        CatchscoreLabel                 matlab.ui.control.Label
        NaCatchDoIt                     matlab.ui.control.Button
        ROI_Crop_Na_Catch               matlab.ui.control.Button
        Ymax_Na_Catch                   matlab.ui.control.NumericEditField
        Ymin_Na_Catch                   matlab.ui.control.NumericEditField
        Xmax_Na_Catch                   matlab.ui.control.NumericEditField
        Xmin_Na_Catch                   matlab.ui.control.NumericEditField
        YRangeLabel_2                   matlab.ui.control.Label
        XRangeLabel_2                   matlab.ui.control.Label
        NaBECquickatomcountingPanel     matlab.ui.container.Panel
        BEC_atom_count                  matlab.ui.control.Button
        BEC_atom_counts                 matlab.ui.control.NumericEditField
        BECcountLabel                   matlab.ui.control.Label
        axial_trap_freq                 matlab.ui.control.NumericEditField
        AxtrapfreqfHzLabel              matlab.ui.control.Label
        radial_trap_freq                matlab.ui.control.NumericEditField
        RadtrapfreqfHzLabel             matlab.ui.control.Label
        um_per_px                       matlab.ui.control.NumericEditField
        umperpxEditFieldLabel           matlab.ui.control.Label
        BEC_TOF                         matlab.ui.control.NumericEditField
        TOFmsEditFieldLabel             matlab.ui.control.Label
        BEC_diameter                    matlab.ui.control.NumericEditField
        BECdiameterpxEditFieldLabel     matlab.ui.control.Label
        BECTab                          matlab.ui.container.Tab
        NaBECQuickAnalysisPanel         matlab.ui.container.Panel
        BECFitDiameter                  matlab.ui.control.NumericEditField
        BECfitdiameterpxEditFieldLabel_2  matlab.ui.control.Label
        Ymax_BEC                        matlab.ui.control.NumericEditField
        Ymin_BEC                        matlab.ui.control.NumericEditField
        Xmax_BEC                        matlab.ui.control.NumericEditField
        Xmin_BEC                        matlab.ui.control.NumericEditField
        YRangeBEC                       matlab.ui.control.Label
        XRangeBEC                       matlab.ui.control.Label
        GuessesPanel                    matlab.ui.container.Panel
        OD_cutoff                       matlab.ui.control.NumericEditField
        BECguessdiameterpxLabel_5       matlab.ui.control.Label
        GuessFitButton                  matlab.ui.control.Button
        ThermalGuessDiameter            matlab.ui.control.NumericEditField
        BECguessdiameterpxLabel_4       matlab.ui.control.Label
        BECGuessDiameter                matlab.ui.control.NumericEditField
        BECguessdiameterpxLabel_3       matlab.ui.control.Label
        ROI_BEC_fit                     matlab.ui.control.Button
        BEC_atom_count_2                matlab.ui.control.Button
        BEC_atom_counts_2               matlab.ui.control.NumericEditField
        BECcountLabel_2                 matlab.ui.control.Label
        UIAxes_BEC                      matlab.ui.control.UIAxes
        UIAxes_thermal                  matlab.ui.control.UIAxes
        UIAxes_bimodal                  matlab.ui.control.UIAxes
        LiLFTab                         matlab.ui.container.Tab
        LiLFQuickAnalysisPanel          matlab.ui.container.Panel
        BECcountLabel_8                 matlab.ui.control.Label
        LiLF_atom_counts_6              matlab.ui.control.NumericEditField
        BECcountLabel_7                 matlab.ui.control.Label
        LiLF_atom_counts_5              matlab.ui.control.NumericEditField
        BECcountLabel_6                 matlab.ui.control.Label
        LiLF_atom_counts_4              matlab.ui.control.NumericEditField
        LiLF_atom_counts_3              matlab.ui.control.NumericEditField
        BECcountLabel_5                 matlab.ui.control.Label
        LiLF_atom_counts_2              matlab.ui.control.NumericEditField
        BECcountLabel_4                 matlab.ui.control.Label
        Ymax_LiLF                       matlab.ui.control.NumericEditField
        Ymin_LiLF                       matlab.ui.control.NumericEditField
        Xmax_LiLF                       matlab.ui.control.NumericEditField
        Xmin_LiLF                       matlab.ui.control.NumericEditField
        YRangeLiLF                      matlab.ui.control.Label
        XRangeLiLF                      matlab.ui.control.Label
        GuessesPanel_2                  matlab.ui.container.Panel
        OD_cutoff_LiLF                  matlab.ui.control.NumericEditField
        BECguessdiameterpxLabel_8       matlab.ui.control.Label
        GuessFitButton_LiLF             matlab.ui.control.Button
        FermiGuessDiameter              matlab.ui.control.NumericEditField
        BECguessdiameterpxLabel_6       matlab.ui.control.Label
        ROI_LiLF_fit                    matlab.ui.control.Button
        LiLF_atom_count                 matlab.ui.control.Button
        LiLF_atom_counts                matlab.ui.control.NumericEditField
        BECcountLabel_3                 matlab.ui.control.Label
        ImageViewerPanel                matlab.ui.container.Panel
        MarkingToolLabel                matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        ShowCheckBox                    matlab.ui.control.CheckBox
        SaveMarkDir                     matlab.ui.control.EditField
        SavemarkButton                  matlab.ui.control.Button
        LoadMarkFile                    matlab.ui.control.EditField
        LoadmarkButton                  matlab.ui.control.Button
        MarkButton                      matlab.ui.control.Button
        Pan                             matlab.ui.control.Button
        Zoom                            matlab.ui.control.Button
        CursorButton                    matlab.ui.control.StateButton
        FrametypeEditField              matlab.ui.control.EditField
        FrametypeEditFieldLabel         matlab.ui.control.Label
        FileSelected                    matlab.ui.control.EditField
        SelectedimageLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        BrowserPanel                    matlab.ui.container.Panel
        Variable                        matlab.ui.control.EditField
        AddVariable                     matlab.ui.control.Button
        metadata_table                  matlab.ui.control.Table
        ShownewCheckBox                 matlab.ui.control.CheckBox
        KillButton                      matlab.ui.control.Button
        ScanCheckBox                    matlab.ui.control.CheckBox
        UITable                         matlab.ui.control.Table
        ImageFolder                     matlab.ui.control.EditField
        RefreshButton                   matlab.ui.control.Button
        FolderButton                    matlab.ui.control.Button
    end


    properties (Access = private)

    end

    properties (Access = public)
        x_mark_data = [];
        y_mark_data = [];
        mark_plot = NaN;
        XMIN = NaN;
        XMAX = NaN;
        YMIN = NaN;
        YMAX = NaN;
        oldSizeX = 0;
        oldSizeY = 0;
        markplot = 0;
        mark = 0; 
        zoom_state = 0;
        pan_state = 0;
        metadata_variables = ["id"; "TOF"; "ImagFreq0"; "ImagFreq1"; "ImagFreq2";"SideGreenEvap";"RF12_Time";"LFImgFreq";"ShakingCycles";"BoxShakeFreq";...
                "BoxShakeAmp"; "Evap2End"];
        metadata_values = [];
        params_for_selected 
        XminPlot = 0;
        XmaxPlot = 0;
        YminPlot = 0;
        YmaxPlot = 0;
        LivePlotCountData = [];
        LivePlotVariableValues = [];
        plotting_data = 0;
        VariableNameX = '';
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            path = uigetdir;
            if not(isempty(path))
                app.ImageFolder.Value = path;
                % list all files with .fits extension
                file_list = dir(fullfile(app.ImageFolder.Value, '*.fits'));
                file_names = {file_list.name};
                file_names = file_names';
                file_names = flip(file_names);

                set(app.UITable,'Data',file_names);
                entries = length(file_names);
                app.UITable.ColumnName = ['FITS images: ', num2str(entries)];
            end
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % list all files with .fits extension
            file_list = dir(fullfile(app.ImageFolder.Value, '*.fits'));
            file_names = {file_list.name};
            file_names = file_names';
            file_names = flip(file_names);

            set(app.UITable,'Data',file_names);
            entries = length(file_names);
            app.UITable.ColumnName = ['FITS images: ', num2str(entries)];

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            imageList = get(app.UITable,'Data');
            app.KillButton.Enable = 'on';
            % set Selected image value:
            app.FileSelected.Value = imageList{indices};
            
            % display image
            display_image(app);

            % SHOW METADATA
            
            % get run id from selected image:
            run_id = extractBefore(app.FileSelected.Value, "_");
            run_id = strcat('x',run_id); % don't ask me why this has to be done LOL
            % first load run params from json file: 
            file_list = dir(fullfile(app.ImageFolder.Value, '*.json'));


            if ~isempty(file_list)
                % allow adding new variables:
                app.AddVariable.Enable='on';
                % now load stuff
                for i=1:length(file_list)
                    if contains(fullfile(file_list(i).folder, file_list(i).name), 'run_params_dump')
                        params_file_fullpath = fullfile(file_list(i).folder, file_list(i).name);
                    end
                end
               
                fid = fopen(params_file_fullpath); % Opening the file
                raw = fread(fid,inf); % Reading the contents
                str = char(raw'); % Transformation
                fclose(fid); % Closing the file
                params = jsondecode(str); % params of all runs

                % get params for run_id:
                app.params_for_selected = params.(run_id);
                % now get metadata from run id:
                app.metadata_values = [];
                for i=1:size(app.metadata_variables)
                    app.metadata_values(i) = string(app.params_for_selected.(app.metadata_variables(i)));
                end
                app.metadata_values = string(app.metadata_values).';
                data = [app.metadata_variables app.metadata_values];
                set(app.metadata_table,'Data',data);
            end


        end

        % Value changed function: FrameType
        function FrameTypeValueChanged(app, event)
            value = app.FrameType.Value;

            app.FrametypeEditField.Value = value;

            % display image
            display_image(app);

        end

        % Button pushed function: LiAtomCounting
        function LiAtomCountingPushed(app, event)

            %%%%%%%%%%%%%%%%%%%%%%%%
            % read the fits file
            img = fitsread(fullfile(app.ImageFolder.Value,app.FileSelected.Value), 'primary');

            %initialize the atom number counting parameter.
            sigma = app.CrossSection.Value;
            pixelsize = app.PixelSize.Value;
            satcount = app.SatCount.Value;
            xmin = app.XminLi.Value;
            xmax = app.XmaxLi.Value;
            ymin = app.YminLi.Value;
            ymax = app.YmaxLi.Value;

            % crop the img
            Xr=size(img(:,:,1),2);
            Yr=size(img(:,:,1),1);
            xmin=max(1,xmin);
            xmax=min(Xr,xmax);
            ymin=max(1,ymin);
            ymax=min(Yr,ymax);

            atomcount_img = img(ymin:ymax,xmin:xmax,:);
            atomnumber_map = AtomNumber(atomcount_img,pixelsize,sigma, satcount);
            Li_atomnumber = sum(sum(atomnumber_map));

            app.LiAtomNumber.Value = Li_atomnumber;


        end

        % Button pushed function: BEC_atom_count
        function BEC_atom_countPushed(app, event)
            axial=app.axial_trap_freq.Value;
            radial=app.radial_trap_freq.Value;
            umpx=app.um_per_px.Value;

            tof=app.BEC_TOF.Value/1000; % convert ms to s
            r=app.BEC_diameter.Value/2;

            hbar = 1.054e-34;
            m = 23*1.67e-27;
            omega = (2*pi*radial*2*pi*radial*2*pi*axial)^(1/3);
            a = 85 * 0.529e-10;

            BEC_cts = ((m/2*(2*pi*radial)^2/(1 + (2*pi*radial*tof)^2))...
                *(umpx*10^(-6)*r)^2)^(5/2)/(15*hbar^2*m^(1/2)*omega^3*a/2^(5/2));

            app.BEC_atom_counts.Value = BEC_cts;

        end

        % Button pushed function: ROI_Crop_Li
        function ROI_Crop_LiButtonPushed(app, event)
            [~,rect]=imcrop(app.UIAxes);
            xmin=round(rect(1));
            ymin=round(rect(2));
            xmax=round(rect(3))+xmin-1;
            ymax=round(rect(4))+ymin-1;

            app.XminLi.Value = xmin;
            app.XmaxLi.Value = xmax;
            app.YminLi.Value = ymin;
            app.YmaxLi.Value = ymax;
        end

        % Button pushed function: NaCatchDoIt
        function NaCatchDoItButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%
            % read the fits file
            img = fitsread(fullfile(app.ImageFolder.Value,app.FileSelected.Value), 'primary');

            %initialize the atom number counting parameter.
            xmin = app.Xmin_Na_Catch.Value;
            xmax = app.Xmax_Na_Catch.Value;
            ymin = app.Ymin_Na_Catch.Value;
            ymax = app.Ymax_Na_Catch.Value;


            % initialize background counting params
            xminBG = app.Xmin_Background.Value;
            xmaxBG = app.Xmax_Background.Value;
            yminBG = app.Ymin_Background.Value;
            ymaxBG = app.Ymax_Background.Value;

            % crop the img
            Xr=size(img(:,:,1),2);
            Yr=size(img(:,:,1),1);
            xmin=max(1,xmin);
            xmax=min(Xr,xmax);
            ymin=max(1,ymin);
            ymax=min(Yr,ymax);


            % crop the img for background
            Xrr=size(img(:,:,1),2);
            Yrr=size(img(:,:,1),1);
            xminBG=max(1,xminBG);
            xmaxBG=min(Xrr,xmaxBG);
            yminBG=max(1,yminBG);
            ymaxBG=min(Yrr,ymaxBG);

            % compute areas:
            BG_area = (ymaxBG-yminBG)*(xmaxBG - xminBG);
            Catch_area = (ymax-ymin)*(xmax-xmin);

            % for the mag trap
            ct_img = img(ymin:ymax,xmin:xmax,:);
            pixel_OD_number = real(-log((ct_img(:,:,1)-ct_img(:,:,3))./(ct_img(:,:,2)-ct_img(:,:,3))));

            % for the background
            bg_img = img(yminBG:ymaxBG,xminBG:xmaxBG,:);
            pixel_OD_number_BG = real(-log((bg_img(:,:,1)-bg_img(:,:,3))./(bg_img(:,:,2)-bg_img(:,:,3))));

            % turn nan and inf to 0 so that they don't count
            pixel_OD_number(isinf(pixel_OD_number)) = 0;
            pixel_OD_number(isnan(pixel_OD_number)) = 0;

            pixel_OD_number_BG(isinf(pixel_OD_number_BG)) = 0;
            pixel_OD_number_BG(isnan(pixel_OD_number_BG)) = 0;

            % take sums...
            NaCatchPxCount_WO_BG = sum(sum(pixel_OD_number));
            BG = sum(sum(pixel_OD_number_BG));

            % convert BG into total BG under catch ROI:
            BG = round(Catch_area*(BG/BG_area));
            
            % final score = catch - bg
            app.NaCatchPxCount.Value = NaCatchPxCount_WO_BG-BG;
            app.NaCatchDensity.Value = app.NaCatchPxCount.Value/Catch_area;

        end

        % Button pushed function: ROI_Crop_Na_Catch
        function ROI_Crop_Na_CatchButtonPushed(app, event)
            [~,rect]=imcrop(app.UIAxes);
            xmin=round(rect(1));
            ymin=round(rect(2));
            xmax=round(rect(3))+xmin-1;
            ymax=round(rect(4))+ymin-1;

            app.Xmin_Na_Catch.Value = xmin;
            app.Xmax_Na_Catch.Value = xmax;
            app.Ymin_Na_Catch.Value = ymin;
            app.Ymax_Na_Catch.Value = ymax;
        end

        % Value changed function: ScanCheckBox
        function ScanCheckBoxValueChanged(app, event)
            value = app.ScanCheckBox.Value;

            if value == 1
                while value == 1
                    % if checkbox is checked then basicall hit refresh every 3s
                    % then display the item #1 of the table if new entry is
                    % detected.

                    % first find how many entries there are currently:
                    table_columnName = app.UITable.ColumnName;
                    old_entries = table_columnName(14:end);

                    if isempty(old_entries)
                        % if there's nothing in the table, then do nothing
                        pause(0.5);
                    else
                        % convert to number
                        old_entries = str2num(old_entries);

                        % now refresh
                        % list all files with .fits extension
                        file_list = dir(fullfile(app.ImageFolder.Value, '*.fits'));
                        file_names = {file_list.name};
                        file_names = file_names';
                        file_names = flip(file_names);

                        set(app.UITable,'Data',file_names);
                        entries = length(file_names);
                        app.UITable.ColumnName = ['FITS images: ', num2str(entries)];

                        % turn off delete button if there is nothing to del
                        if entries == 0
                            app.KillButton.Enable = 'on';
                        end

                        % if show new is on, then show new...
                        if app.ShownewCheckBox.Value == 1
                            % make the selected image the new image
                            % which is positioned at {1}

                            if entries == old_entries + 1
                                imageList = get(app.UITable,'Data');
                                app.FileSelected.Value = imageList{1};
                                display_image(app);
                            end
                        end

                        pause(0.5);
                    end
                    value = app.ScanCheckBox.Value;


                end
            end


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % do nothing
        end

        % Value changed function: CursorButton
        function CursorButtonValueChanged(app, event)
            value = app.CursorButton.Value;

            state = app.CursorButton.Value;
            if state == 1
                datacursormode(app.UIFigure,'on');
            else
                datacursormode(app.UIFigure,'off');
            end

        end

        % Button pushed function: MarkButton
        function MarkButtonPushed(app, event)

            app.mark = drawfreehand(app.UIAxes, 'FaceAlpha', 0);

            xy = app.mark.Position;
            app.x_mark_data = xy(:, 1);
            app.y_mark_data = xy(:, 2);

            hold(app.UIAxes, 'on'); % Don't blow away the image, then plot mark
            app.markplot = plot(app.x_mark_data, app.y_mark_data, 'LineWidth', 2,'Color','green','Parent', app.UIAxes);

            % once done, disable the button:
            app.MarkButton.Enable = 'off';
            
        end

        % Button pushed function: SavemarkButton
        function SavemarkButtonPushed(app, event)

            t = datetime('now');
            DateString = datestr(t,'yyyy-mm-dd_HH-MM-SS');
            mark_save_directory = fullfile(app.ImageFolder.Value,['mark_',DateString,'.mat']);
            x = app.x_mark_data;
            y = app.y_mark_data;
            save(mark_save_directory,'x','y');

            app.SaveMarkDir.Value = mark_save_directory;

        end

        % Button pushed function: LoadmarkButton
        function LoadmarkButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            fullpath = fullfile(path,file);
            app.LoadMarkFile.Value = fullpath;

            % read MAT file
            m = matfile(fullpath);

            app.x_mark_data = m.x;
            app.y_mark_data = m.y;

        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            
            % round value on slider to discretize
            value = app.BrightnessSlider.Value;
            
            rounded = round(value);
            app.BrightnessSlider.Value = rounded;
            
            if not(isempty(app.FileSelected.Value))
                display_image(app);
            end
            
        end

        % Size changed function: LightPanel
        function LightPanelSizeChanged(app, event)
            counting_position = app.NaBECquickatomcountingPanel.Position;
            
            app.LightPanel.Position = [8,counting_position(2)+158,counting_position(3),128];
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            if app.markplot ~= 0
                app.UIAxes;
                delete(app.markplot);
                app.x_mark_data = [];
                app.y_mark_data = [];
                app.markplot = 0;
                app.mark.delete();

                display_image(app);

                % after clearing, enable mark button again:
                app.MarkButton.Enable = 'on';

    
            end

        end

        % Button pushed function: Zoom
        function ZoomPushed(app, event)

            if app.zoom_state == 0
                % turning zoom on:
                app.MarkButton.Enable = 'off';
                app.CursorButton.Enable = 'off';
                app.ClearButton.Enable = 'off';
                app.FolderButton.Enable = 'off';
                app.RefreshButton.Enable = 'off';
                app.ScanCheckBox.Enable = 'off';
                app.Pan.Enable = 'off';
                app.LoadmarkButton.Enable = 'off';
                app.SavemarkButton.Enable = 'off';
                app.ROI_Crop_Li.Enable = 'off';
                app.ROI_Crop_Na_Catch.Enable = 'off';
                app.NaCatchDoIt.Enable = 'off';
                app.LiAtomCounting.Enable = 'off';
                app.BEC_atom_count.Enable = 'off';

                zoom(app.UIAxes,'on');
                app.zoom_state = 1;
                app.Zoom.Text = 'STOP';
                app.Zoom.FontColor = 'r';
            else
                % turning zoom off:
                % turning zoom on:
                app.MarkButton.Enable = 'on';
                app.CursorButton.Enable = 'on';
                app.ClearButton.Enable = 'on';
                app.FolderButton.Enable = 'on';
                app.RefreshButton.Enable = 'on';
                app.ScanCheckBox.Enable = 'on';
                app.Pan.Enable = 'on';
                app.LoadmarkButton.Enable = 'on';
                app.SavemarkButton.Enable = 'on';
                app.ROI_Crop_Li.Enable = 'on';
                app.ROI_Crop_Na_Catch.Enable = 'on';
                app.NaCatchDoIt.Enable = 'on';
                app.LiAtomCounting.Enable = 'on';
                app.BEC_atom_count.Enable = 'on';

                zoom(app.UIAxes,'off');
                app.zoom_state = 0;
                app.Zoom.Text = 'Zoom';
                app.Zoom.FontColor = 'k';


            end

        end

        % Button pushed function: Pan
        function PanButtonPushed(app, event)
            if app.pan_state == 0
                % turning pan on:
                app.MarkButton.Enable = 'off';
                app.CursorButton.Enable = 'off';
                app.ClearButton.Enable = 'off';
                app.FolderButton.Enable = 'off';
                app.RefreshButton.Enable = 'off';
                app.ScanCheckBox.Enable = 'off';
                app.Zoom.Enable = 'off';
                app.LoadmarkButton.Enable = 'off';
                app.SavemarkButton.Enable = 'off';
                app.ROI_Crop_Li.Enable = 'off';
                app.ROI_Crop_Na_Catch.Enable = 'off';
                app.NaCatchDoIt.Enable = 'off';
                app.LiAtomCounting.Enable = 'off';
                app.BEC_atom_count.Enable = 'off';

                pan(app.UIAxes,'on');
                app.pan_state = 1;
                app.Pan.Text = 'STOP';
                app.Pan.FontColor = 'r';
            else
                % turning pan off:
                % turning pan on:
                app.MarkButton.Enable = 'on';
                app.CursorButton.Enable = 'on';
                app.ClearButton.Enable = 'on';
                app.FolderButton.Enable = 'on';
                app.RefreshButton.Enable = 'on';
                app.ScanCheckBox.Enable = 'on';
                app.Zoom.Enable = 'on';
                app.LoadmarkButton.Enable = 'on';
                app.SavemarkButton.Enable = 'on';
                app.ROI_Crop_Li.Enable = 'on';
                app.ROI_Crop_Na_Catch.Enable = 'on';
                app.NaCatchDoIt.Enable = 'on';
                app.LiAtomCounting.Enable = 'on';
                app.BEC_atom_count.Enable = 'on';

                pan(app.UIAxes,'off');
                app.pan_state = 0;
                app.Pan.Text = 'Pan';
                app.Pan.FontColor = 'k';


            end
        end

        % Button pushed function: KillButton
        function KillButtonPushed(app, event)
            selected_file_name = app.FileSelected.Value;
            fullpath = fullfile(app.ImageFolder.Value,app.FileSelected.Value);
 
            if length(selected_file_name) >= 1 % if some file is selected
                delete(fullpath);
            end

            % refresh now
            file_list = dir(fullfile(app.ImageFolder.Value, '*.fits'));
            file_names = {file_list.name};
            file_names = file_names';
            file_names = flip(file_names);

            set(app.UITable,'Data',file_names);
            entries = length(file_names);
            app.UITable.ColumnName = ['FITS images: ', num2str(entries)];


        end

        % Value changed function: ShownewCheckBox
        function ShownewCheckBoxValueChanged(app, event)
            value = app.ShownewCheckBox.Value;
            
        end

        % Button pushed function: ROI_Crop_Background
        function ROI_Crop_BackgroundButtonPushed(app, event)
            [~,rect]=imcrop(app.UIAxes);
            xminBG=round(rect(1));
            yminBG=round(rect(2));
            xmaxBG=round(rect(3))+xminBG-1;
            ymaxBG=round(rect(4))+yminBG-1;

            app.Xmin_Background.Value = xminBG;
            app.Xmax_Background.Value = xmaxBG;
            app.Ymin_Background.Value = yminBG;
            app.Ymax_Background.Value = ymaxBG;   
        end

        % Button pushed function: AddVariable
        function AddVariableButtonPushed(app, event)

            new_var_name = strtrim(app.Variable.Value); % removes all tabs and spaces
            % act if field exists in datastructure and is not empty
            if isfield(app.params_for_selected,new_var_name) && ~isempty(app.params_for_selected.(new_var_name))
                if ~ismember(new_var_name, app.metadata_variables) % only add if new 
                    l = size(app.metadata_variables);
                    app.metadata_variables(l(1)+1) = new_var_name;
                end
            end

            % update table
            app.metadata_values = [];
            for i=1:size(app.metadata_variables)
               app.metadata_values(i) = string(app.params_for_selected.(app.metadata_variables(i)));
            end
            app.metadata_values = string(app.metadata_values).';
            data = [app.metadata_variables app.metadata_values];
            set(app.metadata_table,'Data',data);

        end

        % Button pushed function: ROI_Crop_Plot
        function ROI_Crop_PlotButtonPushed(app, event)
            [~,rect]=imcrop(app.UIAxes);
            xmin=round(rect(1));
            ymin=round(rect(2));
            xmax=round(rect(3))+xmin-1;
            ymax=round(rect(4))+ymin-1;

            app.XminPlot = xmin;
            app.XmaxPlot = xmax;
            app.YminPlot = ymin;
            app.YmaxPlot = ymax;

        end

        % Button pushed function: SumPlotting
        function SumPlottingButtonPushed(app, event)
            % now do counting... scaled version:
            %%%%%%%%%%%%%%%%%%%%%%%%
            % read the fits file
            img = fitsread(fullfile(app.ImageFolder.Value,app.FileSelected.Value), 'primary');

            %initialize the atom number counting parameters
            xmin = app.XminPlot;
            xmax = app.XmaxPlot;
            ymin = app.YminPlot;
            ymax = app.YmaxPlot;

            % crop the img
            Xr=size(img(:,:,1),2);
            Yr=size(img(:,:,1),1);
            xmin=max(1,xmin);
            xmax=min(Xr,xmax);
            ymin=max(1,ymin);
            ymax=min(Yr,ymax);

            pixelSum_img = img(ymin:ymax,xmin:xmax,:);
            pixelSum_map = PixelSumPlotting(pixelSum_img);
            pixelSum = sum(sum(pixelSum_map));

            %disp(pixelSum)
            %disp(app.params_for_selected.(app.VariableNameX));

            % update variables
            app.LivePlotCountData(end+1) = pixelSum;
            app.LivePlotVariableValues(end+1) = app.params_for_selected.(app.VariableNameX);

            % plot after updating plotting data:
            plot(app.UIAxes2, app.LivePlotVariableValues, app.LivePlotCountData, '.k', 'MarkerSize',10);

            % allow undo plot:
            app.UndoPlot.Enable = 'on';
            app.ROI_Crop_Plot.Enable = 'off';
    
        end

        % Button pushed function: AddPlottingVariable
        function AddPlottingVariableButtonPushed(app, event)
            var_name = strtrim(app.PlottingVariable.Value); % removes all tabs and spaces
            % act if field exists in datastructure and is not empty
            if isfield(app.params_for_selected,var_name) && ~isempty(app.params_for_selected.(var_name))
                app.VariableNameX = var_name;
                app.SumPlotting.Enable = 'on';
                app.AddPlottingVariable.Enable = 'off';
                app.PlottingVariable.Enable = 'off';
                app.ClearPlot.Enable = 'on';
                app.UIAxes2.XLabel.String = erase(var_name,'_');
            end
        end

        % Button pushed function: ClearPlot
        function ClearPlotButtonPushed(app, event)
            app.AddPlottingVariable.Enable = 'on';
            app.PlottingVariable.Enable = 'on';
            app.LivePlotCountData = [];
            app.LivePlotVariableValues = [];
            plot(app.UIAxes2, app.LivePlotVariableValues, app.LivePlotCountData, '.k', 'MarkerSize',10);
            app.ROI_Crop_Plot.Enable = "on";
            app.SumPlotting.Enable = 'off';
            app.UndoPlot.Enable = 'off';
            app.PlottingVariable.Value = '';
        end

        % Button pushed function: UndoPlot
        function UndoPlotButtonPushed(app, event)
            % remove last point from plot:
            if ~isempty(app.LivePlotCountData)
                app.LivePlotCountData(end) = [];
                app.LivePlotVariableValues(end) = [];
                plot(app.UIAxes2, app.LivePlotVariableValues, app.LivePlotCountData, '.k', 'MarkerSize',10);
            else
                app.UndoPlot.Enable = 'off';
            end
            
        end

        % Callback function
        function ROI_BEC_fitButtonPushed(app, event)
            
        end

        % Button pushed function: ROI_BEC_fit
        function ROI_BEC_fitButtonPushed2(app, event)
            [~,rect]=imcrop(app.UIAxes);
            xmin=round(rect(1));
            ymin=round(rect(2));
            xmax=round(rect(3))+xmin-1;
            ymax=round(rect(4))+ymin-1;

            app.Xmin_BEC.Value = xmin;
            app.Xmax_BEC.Value = xmax;
            app.Ymin_BEC.Value = ymin;
            app.Ymax_BEC.Value = ymax;

        end

        % Button pushed function: GuessFitButton
        function GuessFitButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%
            % read the fits file
            img = fitsread(fullfile(app.ImageFolder.Value,app.FileSelected.Value), 'primary');

            % crop the img
            Xr=size(img(:,:,1),2);
            Yr=size(img(:,:,1),1);
            xmin=max(1,app.Xmin_BEC.Value);
            xmax=min(Xr,app.Xmax_BEC.Value);
            ymin=max(1,app.Ymin_BEC.Value);
            ymax=min(Yr,app.Ymax_BEC.Value);

            rho_thermal_guess = round(app.ThermalGuessDiameter.Value/2);
            rho_condensate_guess = round(app.BECGuessDiameter.Value/2);

            BEC_fit_img = img(ymin:ymax,xmin:xmax,:);
            BEC_fit(BEC_fit_img, rho_thermal_guess, rho_condensate_guess, app);
        end

        % Button pushed function: BEC_atom_count_2
        function BEC_atom_count_2ButtonPushed(app, event)
            axial=app.axial_trap_freq.Value;
            radial=app.radial_trap_freq.Value;
            umpx=app.um_per_px.Value;

            tof=app.BEC_TOF.Value/1000; % convert ms to s
            r=app.BECFitDiameter.Value/2;

            hbar = 1.054e-34;
            m = 23*1.67e-27;
            omega = (2*pi*radial*2*pi*radial*2*pi*axial)^(1/3);
            a = 85 * 0.529e-10;

            BEC_cts = ((m/2*(2*pi*radial)^2/(1 + (2*pi*radial*tof)^2))...
                *(umpx*10^(-6)*r)^2)^(5/2)/(15*hbar^2*m^(1/2)*omega^3*a/2^(5/2));

            app.BEC_atom_counts_2.Value = BEC_cts;
        end

        % Button pushed function: ROI_LiLF_fit
        function ROI_LiLF_fitButtonPushed(app, event)
            [~,rect]=imcrop(app.UIAxes);
            xmin=round(rect(1));
            ymin=round(rect(2));
            xmax=round(rect(3))+xmin-1;
            ymax=round(rect(4))+ymin-1;

            app.Xmin_LiLF.Value = xmin;
            app.Xmax_LiLF.Value = xmax;
            app.Ymin_LiLF.Value = ymin;
            app.Ymax_LiLF.Value = ymax;
        end

        % Button pushed function: GuessFitButton_LiLF
        function GuessFitButton_LiLFPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%
            % read the fits file
            img = fitsread(fullfile(app.ImageFolder.Value,app.FileSelected.Value), 'primary');

            % crop the img
            Xr=size(img(:,:,1),2);
            Yr=size(img(:,:,1),1);
            xmin=max(1,app.Xmin_LiLF.Value);
            xmax=min(Xr,app.Xmax_LiLF.Value);
            ymin=max(1,app.Ymin_LiLF.Value);
            ymax=min(Yr,app.Ymax_LiLF.Value);

            rho_guess = round(app.FermiGuessDiameter.Value/2);

            LiLF_fit_img = img(ymin:ymax,xmin:xmax,:);
            LiLF_fit(LiLF_fit_img, rho_guess, app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowserPanel = uipanel(app.UIFigure);
            app.BrowserPanel.Title = 'Browser';
            app.FolderButton = uibutton(app.BrowserPanel, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Tag = 'folder_name';
            app.FolderButton.Text = 'Folder';
            app.RefreshButton = uibutton(app.BrowserPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ImageFolder = uieditfield(app.BrowserPanel, 'text');
            app.ImageFolder.Tag = 'path_name';
            app.UITable = uitable(app.BrowserPanel);
            app.UITable.ColumnName = {'FITS images:'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ScanCheckBox = uicheckbox(app.BrowserPanel);
            app.ScanCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScanCheckBoxValueChanged, true);
            app.ScanCheckBox.Text = ' Scan';
            app.KillButton = uibutton(app.BrowserPanel, 'push');
            app.KillButton.ButtonPushedFcn = createCallbackFcn(app, @KillButtonPushed, true);
            app.KillButton.Enable = 'off';
            app.KillButton.Text = 'Kill';
            app.ShownewCheckBox = uicheckbox(app.BrowserPanel);
            app.ShownewCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShownewCheckBoxValueChanged, true);
            app.ShownewCheckBox.Text = 'Show new';
            app.metadata_table = uitable(app.BrowserPanel);
            app.metadata_table.ColumnName = {'Parameter'; 'Value'};
            app.metadata_table.RowName = {};
            app.AddVariable = uibutton(app.BrowserPanel, 'push');
            app.AddVariable.ButtonPushedFcn = createCallbackFcn(app, @AddVariableButtonPushed, true);
            app.AddVariable.Enable = 'off';
            app.AddVariable.Text = 'Add';
            app.Variable = uieditfield(app.BrowserPanel, 'text');
            app.ImageViewerPanel = uipanel(app.UIFigure);
            app.ImageViewerPanel.Title = 'Image Viewer';
            app.UIAxes = uiaxes(app.ImageViewerPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SelectedimageLabel = uilabel(app.ImageViewerPanel);
            app.SelectedimageLabel.Text = 'Selected image: ';
            app.FileSelected = uieditfield(app.ImageViewerPanel, 'text');
            app.FrametypeEditFieldLabel = uilabel(app.ImageViewerPanel);
            app.FrametypeEditFieldLabel.Text = 'Frame type:';
            app.FrametypeEditField = uieditfield(app.ImageViewerPanel, 'text');
            app.FrametypeEditField.Value = 'OD';
            app.CursorButton = uibutton(app.ImageViewerPanel, 'state');
            app.CursorButton.ValueChangedFcn = createCallbackFcn(app, @CursorButtonValueChanged, true);
            app.CursorButton.Text = 'Cursor';
            app.Zoom = uibutton(app.ImageViewerPanel, 'push');
            app.Zoom.ButtonPushedFcn = createCallbackFcn(app, @ZoomPushed, true);
            app.Zoom.Text = 'Zoom';
            app.Pan = uibutton(app.ImageViewerPanel, 'push');
            app.Pan.ButtonPushedFcn = createCallbackFcn(app, @PanButtonPushed, true);
            app.Pan.Text = 'Pan';
            app.MarkButton = uibutton(app.ImageViewerPanel, 'push');
            app.MarkButton.ButtonPushedFcn = createCallbackFcn(app, @MarkButtonPushed, true);
            app.MarkButton.Text = 'Mark';
            app.LoadmarkButton = uibutton(app.ImageViewerPanel, 'push');
            app.LoadmarkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmarkButtonPushed, true);
            app.LoadmarkButton.Text = 'Load mark';
            app.LoadMarkFile = uieditfield(app.ImageViewerPanel, 'text');
            app.SavemarkButton = uibutton(app.ImageViewerPanel, 'push');
            app.SavemarkButton.ButtonPushedFcn = createCallbackFcn(app, @SavemarkButtonPushed, true);
            app.SavemarkButton.Text = 'Save mark';
            app.SaveMarkDir = uieditfield(app.ImageViewerPanel, 'text');
            app.ShowCheckBox = uicheckbox(app.ImageViewerPanel);
            app.ShowCheckBox.Text = 'Show';
            app.ShowCheckBox.Value = true;
            app.ClearButton = uibutton(app.ImageViewerPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.MarkingToolLabel = uilabel(app.ImageViewerPanel);
            app.MarkingToolLabel.Text = 'Marking Tool:';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.NaBECquickatomcountingPanel = uipanel(app.ToolsTab);
            app.NaBECquickatomcountingPanel.Title = 'Na BEC quick atom counting';
            app.BECdiameterpxEditFieldLabel = uilabel(app.NaBECquickatomcountingPanel);
            app.BECdiameterpxEditFieldLabel.Text = 'BEC diameter (px)';
            app.BEC_diameter = uieditfield(app.NaBECquickatomcountingPanel, 'numeric');
            app.BEC_diameter.Value = 70;
            app.TOFmsEditFieldLabel = uilabel(app.NaBECquickatomcountingPanel);
            app.TOFmsEditFieldLabel.Text = 'TOF (ms)';
            app.BEC_TOF = uieditfield(app.NaBECquickatomcountingPanel, 'numeric');
            app.BEC_TOF.Value = 18;
            app.umperpxEditFieldLabel = uilabel(app.NaBECquickatomcountingPanel);
            app.umperpxEditFieldLabel.Text = 'um per px';
            app.um_per_px = uieditfield(app.NaBECquickatomcountingPanel, 'numeric');
            app.um_per_px.Value = 5;
            app.RadtrapfreqfHzLabel = uilabel(app.NaBECquickatomcountingPanel);
            app.RadtrapfreqfHzLabel.Text = 'Rad. trap. (f,Hz)';
            app.radial_trap_freq = uieditfield(app.NaBECquickatomcountingPanel, 'numeric');
            app.radial_trap_freq.Value = 95;
            app.AxtrapfreqfHzLabel = uilabel(app.NaBECquickatomcountingPanel);
            app.AxtrapfreqfHzLabel.Text = 'Ax. trap. (f,Hz)';
            app.axial_trap_freq = uieditfield(app.NaBECquickatomcountingPanel, 'numeric');
            app.axial_trap_freq.Value = 12;
            app.BECcountLabel = uilabel(app.NaBECquickatomcountingPanel);
            app.BECcountLabel.Text = 'BEC count';
            app.BEC_atom_counts = uieditfield(app.NaBECquickatomcountingPanel, 'numeric');
            app.BEC_atom_count = uibutton(app.NaBECquickatomcountingPanel, 'push');
            app.BEC_atom_count.ButtonPushedFcn = createCallbackFcn(app, @BEC_atom_countPushed, true);
            app.BEC_atom_count.Text = 'Do it!';
            app.NaCatchpixelcountingPanel = uipanel(app.ToolsTab);
            app.NaCatchpixelcountingPanel.Title = 'Na Catch pixel counting';
            app.XRangeLabel_2 = uilabel(app.NaCatchpixelcountingPanel);
            app.XRangeLabel_2.Text = 'X Range';
            app.YRangeLabel_2 = uilabel(app.NaCatchpixelcountingPanel);
            app.YRangeLabel_2.Text = 'Y Range';
            app.Xmin_Na_Catch = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Xmin_Na_Catch.Value = 1;
            app.Xmax_Na_Catch = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Xmax_Na_Catch.Value = 512;
            app.Ymin_Na_Catch = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Ymin_Na_Catch.Value = 1;
            app.Ymax_Na_Catch = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Ymax_Na_Catch.Value = 512;
            app.ROI_Crop_Na_Catch = uibutton(app.NaCatchpixelcountingPanel, 'push');
            app.ROI_Crop_Na_Catch.ButtonPushedFcn = createCallbackFcn(app, @ROI_Crop_Na_CatchButtonPushed, true);
            app.ROI_Crop_Na_Catch.Text = 'ROI Crop';
            app.NaCatchDoIt = uibutton(app.NaCatchpixelcountingPanel, 'push');
            app.NaCatchDoIt.ButtonPushedFcn = createCallbackFcn(app, @NaCatchDoItButtonPushed, true);
            app.NaCatchDoIt.Text = 'Do it!';
            app.CatchscoreLabel = uilabel(app.NaCatchpixelcountingPanel);
            app.CatchscoreLabel.Text = 'Catch score';
            app.NaCatchPxCount = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.XRangeLabel_3 = uilabel(app.NaCatchpixelcountingPanel);
            app.XRangeLabel_3.Text = 'X Range';
            app.YRangeLabel_3 = uilabel(app.NaCatchpixelcountingPanel);
            app.YRangeLabel_3.Text = 'Y Range';
            app.Xmin_Background = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Xmin_Background.Value = 1;
            app.Xmax_Background = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Xmax_Background.Value = 512;
            app.Ymin_Background = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Ymin_Background.Value = 1;
            app.Ymax_Background = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Ymax_Background.Value = 512;
            app.ROI_Crop_Background = uibutton(app.NaCatchpixelcountingPanel, 'push');
            app.ROI_Crop_Background.ButtonPushedFcn = createCallbackFcn(app, @ROI_Crop_BackgroundButtonPushed, true);
            app.ROI_Crop_Background.Text = 'ROI Crop';
            app.BackgroundROILabel = uilabel(app.NaCatchpixelcountingPanel);
            app.BackgroundROILabel.Text = 'Background ROI:';
            app.CatchROILabel = uilabel(app.NaCatchpixelcountingPanel);
            app.CatchROILabel.Text = 'Catch ROI:';
            app.LiLFatomcountingPanel = uipanel(app.ToolsTab);
            app.LiLFatomcountingPanel.Title = 'Li LF atom counting';
            app.XRangeLabel = uilabel(app.LiLFatomcountingPanel);
            app.XRangeLabel.Text = 'X Range';
            app.YRangeLabel = uilabel(app.LiLFatomcountingPanel);
            app.YRangeLabel.Text = 'Y Range';
            app.XminLi = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.XminLi.Value = 1;
            app.XmaxLi = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.XmaxLi.Value = 512;
            app.YminLi = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.YminLi.Value = 1;
            app.YmaxLi = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.YmaxLi.Value = 512;
            app.ROI_Crop_Li = uibutton(app.LiLFatomcountingPanel, 'push');
            app.ROI_Crop_Li.ButtonPushedFcn = createCallbackFcn(app, @ROI_Crop_LiButtonPushed, true);
            app.ROI_Crop_Li.Text = 'ROI Crop';
            app.PixelSizeum2EditFieldLabel = uilabel(app.LiLFatomcountingPanel);
            app.PixelSizeum2EditFieldLabel.Text = 'Pixel Size (um^2)';
            app.PixelSize = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.PixelSize.Value = 27.52;
            app.CrossSectionum2EditFieldLabel = uilabel(app.LiLFatomcountingPanel);
            app.CrossSectionum2EditFieldLabel.Text = 'Cross Section (um^2)';
            app.CrossSection = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.CrossSection.Value = 0.215;
            app.SaturationCountsEditFieldLabel = uilabel(app.LiLFatomcountingPanel);
            app.SaturationCountsEditFieldLabel.Text = 'Saturation Counts';
            app.SatCount = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.SatCount.Value = 133570;
            app.AtomnumberEditFieldLabel = uilabel(app.LiLFatomcountingPanel);
            app.AtomnumberEditFieldLabel.Text = 'Atom number';
            app.LiAtomNumber = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.LiAtomCounting = uibutton(app.LiLFatomcountingPanel, 'push');
            app.LiAtomCounting.ButtonPushedFcn = createCallbackFcn(app, @LiAtomCountingPushed, true);
            app.LiAtomCounting.Text = 'Do it!';
            app.LightPanel = uipanel(app.ToolsTab);
            app.LightPanel.Title = 'Light';
            app.LightPanel.SizeChangedFcn = createCallbackFcn(app, @LightPanelSizeChanged, true);
            app.FrameType = uilistbox(app.LightPanel);
            app.FrameType.Items = {'OD', 'With atoms', 'Without atoms', 'Dark', 'FakeOD'};
            app.FrameType.ValueChangedFcn = createCallbackFcn(app, @FrameTypeValueChanged, true);
            app.FrameType.Value = 'OD';
            app.MinEditField = uieditfield(app.LightPanel, 'numeric');
            app.MinEditFieldLabel = uilabel(app.LightPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MaxEditFieldLabel = uilabel(app.LightPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.LightPanel, 'numeric');
            app.MaxEditField.Value = 1.3;
            app.BrightnessSliderLabel = uilabel(app.LightPanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.LightPanel);
            app.BrightnessSlider.Limits = [8 16];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [9 11 13 15];
            app.BrightnessSlider.Value = 14;
            app.LiveplottingPanel = uipanel(app.ToolsTab);
            app.LiveplottingPanel.Title = 'Live plotting';
            app.UIAxes2 = uiaxes(app.LiveplottingPanel);
            xlabel(app.UIAxes2, 'X')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = '';
            app.ClearPlot = uibutton(app.LiveplottingPanel, 'push');
            app.ClearPlot.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlot.Enable = 'off';
            app.ClearPlot.Text = 'Clear';
            app.AddPlottingVariable = uibutton(app.LiveplottingPanel, 'push');
            app.AddPlottingVariable.ButtonPushedFcn = createCallbackFcn(app, @AddPlottingVariableButtonPushed, true);
            app.AddPlottingVariable.Text = 'Add';
            app.ROI_Crop_Plot = uibutton(app.LiveplottingPanel, 'push');
            app.ROI_Crop_Plot.ButtonPushedFcn = createCallbackFcn(app, @ROI_Crop_PlotButtonPushed, true);
            app.ROI_Crop_Plot.Text = 'ROI Crop';
            app.LiveplotCheckBox = uicheckbox(app.LiveplottingPanel);
            app.LiveplotCheckBox.Enable = 'off';
            app.LiveplotCheckBox.Text = 'Live plot';
            app.PlottingVariable = uieditfield(app.LiveplottingPanel, 'text');
            app.SumPlotting = uibutton(app.LiveplottingPanel, 'push');
            app.SumPlotting.ButtonPushedFcn = createCallbackFcn(app, @SumPlottingButtonPushed, true);
            app.SumPlotting.Enable = 'off';
            app.SumPlotting.Text = 'Plot';
            app.UndoPlot = uibutton(app.LiveplottingPanel, 'push');
            app.UndoPlot.ButtonPushedFcn = createCallbackFcn(app, @UndoPlotButtonPushed, true);
            app.UndoPlot.Enable = 'off';
            app.UndoPlot.Text = 'Undo';
            app.BECTab = uitab(app.TabGroup);
            app.BECTab.Title = 'BEC';
            app.UIAxes_bimodal = uiaxes(app.BECTab);
            title(app.UIAxes_bimodal, 'Title')
            xlabel(app.UIAxes_bimodal, 'X (px)')
            app.UIAxes_thermal = uiaxes(app.BECTab);
            title(app.UIAxes_thermal, 'Title')
            xlabel(app.UIAxes_thermal, 'X (px)')
            app.UIAxes_BEC = uiaxes(app.BECTab);
            title(app.UIAxes_BEC, 'Title')
            xlabel(app.UIAxes_BEC, 'X (px)')
            app.NaBECQuickAnalysisPanel = uipanel(app.BECTab);
            app.NaBECQuickAnalysisPanel.Title = 'Na BEC Quick Analysis';
            app.BECcountLabel_2 = uilabel(app.NaBECQuickAnalysisPanel);
            app.BECcountLabel_2.Text = 'BEC count';
            app.BEC_atom_counts_2 = uieditfield(app.NaBECQuickAnalysisPanel, 'numeric');
            app.BEC_atom_count_2 = uibutton(app.NaBECQuickAnalysisPanel, 'push');
            app.BEC_atom_count_2.ButtonPushedFcn = createCallbackFcn(app, @BEC_atom_count_2ButtonPushed, true);
            app.BEC_atom_count_2.Text = 'Do it!';
            app.ROI_BEC_fit = uibutton(app.NaBECQuickAnalysisPanel, 'push');
            app.ROI_BEC_fit.ButtonPushedFcn = createCallbackFcn(app, @ROI_BEC_fitButtonPushed2, true);
            app.ROI_BEC_fit.Text = 'ROI crop';
            app.GuessesPanel = uipanel(app.NaBECQuickAnalysisPanel);
            app.GuessesPanel.Title = 'Guesses';
            app.BECguessdiameterpxLabel_3 = uilabel(app.GuessesPanel);
            app.BECguessdiameterpxLabel_3.Text = 'BEC diam. (px)';
            app.BECGuessDiameter = uieditfield(app.GuessesPanel, 'numeric');
            app.BECGuessDiameter.Value = 69;
            app.BECguessdiameterpxLabel_4 = uilabel(app.GuessesPanel);
            app.BECguessdiameterpxLabel_4.Text = 'Thermal diam. (px)';
            app.ThermalGuessDiameter = uieditfield(app.GuessesPanel, 'numeric');
            app.ThermalGuessDiameter.Value = 69;
            app.GuessFitButton = uibutton(app.GuessesPanel, 'push');
            app.GuessFitButton.ButtonPushedFcn = createCallbackFcn(app, @GuessFitButtonPushed, true);
            app.GuessFitButton.Text = 'Guess & Fit';
            app.BECguessdiameterpxLabel_5 = uilabel(app.GuessesPanel);
            app.BECguessdiameterpxLabel_5.Text = 'OD cutoff';
            app.OD_cutoff = uieditfield(app.GuessesPanel, 'numeric');
            app.OD_cutoff.Value = 3.8;
            app.XRangeBEC = uilabel(app.NaBECQuickAnalysisPanel);
            app.XRangeBEC.Text = 'X Range';
            app.YRangeBEC = uilabel(app.NaBECQuickAnalysisPanel);
            app.YRangeBEC.Text = 'Y Range';
            app.Xmin_BEC = uieditfield(app.NaBECQuickAnalysisPanel, 'numeric');
            app.Xmin_BEC.Value = 260;
            app.Xmax_BEC = uieditfield(app.NaBECQuickAnalysisPanel, 'numeric');
            app.Xmax_BEC.Value = 510;
            app.Ymin_BEC = uieditfield(app.NaBECQuickAnalysisPanel, 'numeric');
            app.Ymin_BEC.Value = 2;
            app.Ymax_BEC = uieditfield(app.NaBECQuickAnalysisPanel, 'numeric');
            app.Ymax_BEC.Value = 200;
            app.BECfitdiameterpxEditFieldLabel_2 = uilabel(app.NaBECQuickAnalysisPanel);
            app.BECfitdiameterpxEditFieldLabel_2.Text = 'BEC fit diameter (px)';
            app.BECFitDiameter = uieditfield(app.NaBECQuickAnalysisPanel, 'numeric');
            app.LiLFTab = uitab(app.TabGroup);
            app.LiLFTab.Title = 'Li LF';
            app.LiLFQuickAnalysisPanel = uipanel(app.LiLFTab);
            app.LiLFQuickAnalysisPanel.Title = 'Li LF Quick Analysis';
            app.BECcountLabel_3 = uilabel(app.LiLFQuickAnalysisPanel);
            app.BECcountLabel_3.Text = 'Li LF count (fit)';
            app.LiLF_atom_counts = uieditfield(app.LiLFQuickAnalysisPanel, 'numeric');
            app.LiLF_atom_count = uibutton(app.LiLFQuickAnalysisPanel, 'push');
            app.LiLF_atom_count.Text = 'Do it!';
            app.ROI_LiLF_fit = uibutton(app.LiLFQuickAnalysisPanel, 'push');
            app.ROI_LiLF_fit.ButtonPushedFcn = createCallbackFcn(app, @ROI_LiLF_fitButtonPushed, true);
            app.ROI_LiLF_fit.Text = 'ROI crop';
            app.GuessesPanel_2 = uipanel(app.LiLFQuickAnalysisPanel);
            app.GuessesPanel_2.Title = 'Guesses';
            app.BECguessdiameterpxLabel_6 = uilabel(app.GuessesPanel_2);
            app.BECguessdiameterpxLabel_6.Text = 'Li diam. (px)';
            app.FermiGuessDiameter = uieditfield(app.GuessesPanel_2, 'numeric');
            app.FermiGuessDiameter.Value = 100;
            app.GuessFitButton_LiLF = uibutton(app.GuessesPanel_2, 'push');
            app.GuessFitButton_LiLF.ButtonPushedFcn = createCallbackFcn(app, @GuessFitButton_LiLFPushed, true);
            app.GuessFitButton_LiLF.Text = 'Guess & Fit';
            app.BECguessdiameterpxLabel_8 = uilabel(app.GuessesPanel_2);
            app.BECguessdiameterpxLabel_8.Text = 'OD cutoff';
            app.OD_cutoff_LiLF = uieditfield(app.GuessesPanel_2, 'numeric');
            app.OD_cutoff_LiLF.Value = 3.8;
            app.XRangeLiLF = uilabel(app.LiLFQuickAnalysisPanel);
            app.XRangeLiLF.Text = 'X Range';
            app.YRangeLiLF = uilabel(app.LiLFQuickAnalysisPanel);
            app.YRangeLiLF.Text = 'Y Range';
            app.Xmin_LiLF = uieditfield(app.LiLFQuickAnalysisPanel, 'numeric');
            app.Xmin_LiLF.Value = 250;
            app.Xmax_LiLF = uieditfield(app.LiLFQuickAnalysisPanel, 'numeric');
            app.Xmax_LiLF.Value = 508;
            app.Ymin_LiLF = uieditfield(app.LiLFQuickAnalysisPanel, 'numeric');
            app.Ymin_LiLF.Value = 150;
            app.Ymax_LiLF = uieditfield(app.LiLFQuickAnalysisPanel, 'numeric');
            app.Ymax_LiLF.Value = 472;
            app.BECcountLabel_4 = uilabel(app.LiLFQuickAnalysisPanel);
            app.BECcountLabel_4.Text = 'Li LF count (px)';
            app.LiLF_atom_counts_2 = uieditfield(app.LiLFQuickAnalysisPanel, 'numeric');
            app.BECcountLabel_5 = uilabel(app.LiLFQuickAnalysisPanel);
            app.BECcountLabel_5.Text = 'T (K)';
            app.LiLF_atom_counts_3 = uieditfield(app.LiLFQuickAnalysisPanel, 'numeric');
            app.LiLF_atom_counts_4 = uieditfield(app.LiLFQuickAnalysisPanel, 'numeric');
            app.BECcountLabel_6 = uilabel(app.LiLFQuickAnalysisPanel);
            app.BECcountLabel_6.Text = 'TF (K)';
            app.LiLF_atom_counts_5 = uieditfield(app.LiLFQuickAnalysisPanel, 'numeric');
            app.BECcountLabel_7 = uilabel(app.LiLFQuickAnalysisPanel);
            app.BECcountLabel_7.Text = 'T/TF';
            app.LiLF_atom_counts_6 = uieditfield(app.LiLFQuickAnalysisPanel, 'numeric');
            app.BECcountLabel_8 = uilabel(app.LiLFQuickAnalysisPanel);
            app.BECcountLabel_8.Text = 'TF (fit)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Browser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end