classdef Image_Browser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MeasurementPanel                matlab.ui.container.Panel
        AddButton                       matlab.ui.control.StateButton
        STARTButton                     matlab.ui.control.Button
        ImageSaverStatus                matlab.ui.control.EditField
        ImagesaverstatusLabel           matlab.ui.control.Label
        TopSideSwitch                   matlab.ui.control.Switch
        MeasurementName                 matlab.ui.control.EditField
        CustomfoldernameLabel           matlab.ui.control.Label
        SwitchFolderType                matlab.ui.control.Switch
        ToolsPanel                      matlab.ui.container.Panel
        LightPanel                      matlab.ui.container.Panel
        BrightnessSlider                matlab.ui.control.Slider
        BrightnessSliderLabel           matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        FrameType                       matlab.ui.control.ListBox
        MarkingToolPanel                matlab.ui.container.Panel
        ClearButton                     matlab.ui.control.Button
        ShowCheckBox                    matlab.ui.control.CheckBox
        SaveMarkDir                     matlab.ui.control.EditField
        SavemarkButton                  matlab.ui.control.Button
        LoadMarkFile                    matlab.ui.control.EditField
        LoadmarkButton                  matlab.ui.control.Button
        MarkButton                      matlab.ui.control.Button
        LiLFatomcountingPanel           matlab.ui.container.Panel
        LiAtomCounting                  matlab.ui.control.Button
        LiAtomNumber                    matlab.ui.control.NumericEditField
        AtomnumberEditFieldLabel        matlab.ui.control.Label
        SatCount                        matlab.ui.control.NumericEditField
        SaturationCountsEditFieldLabel  matlab.ui.control.Label
        CrossSection                    matlab.ui.control.NumericEditField
        CrossSectionum2EditFieldLabel   matlab.ui.control.Label
        PixelSize                       matlab.ui.control.NumericEditField
        PixelSizeum2EditFieldLabel      matlab.ui.control.Label
        ROI_Crop_Li                     matlab.ui.control.Button
        YmaxLi                          matlab.ui.control.NumericEditField
        YminLi                          matlab.ui.control.NumericEditField
        XmaxLi                          matlab.ui.control.NumericEditField
        XminLi                          matlab.ui.control.NumericEditField
        YRangeLabel                     matlab.ui.control.Label
        XRangeLabel                     matlab.ui.control.Label
        NaCatchpixelcountingPanel       matlab.ui.container.Panel
        NaCatchDensity                  matlab.ui.control.NumericEditField
        CatchscoreLabel_2               matlab.ui.control.Label
        CatchROILabel                   matlab.ui.control.Label
        BackgroundROILabel              matlab.ui.control.Label
        ROI_Crop_Background             matlab.ui.control.Button
        Ymax_Background                 matlab.ui.control.NumericEditField
        Ymin_Background                 matlab.ui.control.NumericEditField
        Xmax_Background                 matlab.ui.control.NumericEditField
        Xmin_Background                 matlab.ui.control.NumericEditField
        YRangeLabel_3                   matlab.ui.control.Label
        XRangeLabel_3                   matlab.ui.control.Label
        NaCatchPxCount                  matlab.ui.control.NumericEditField
        CatchscoreLabel                 matlab.ui.control.Label
        NaCatchDoIt                     matlab.ui.control.Button
        ROI_Crop_Na_Catch               matlab.ui.control.Button
        Ymax_Na_Catch                   matlab.ui.control.NumericEditField
        Ymin_Na_Catch                   matlab.ui.control.NumericEditField
        Xmax_Na_Catch                   matlab.ui.control.NumericEditField
        Xmin_Na_Catch                   matlab.ui.control.NumericEditField
        YRangeLabel_2                   matlab.ui.control.Label
        XRangeLabel_2                   matlab.ui.control.Label
        NaBECatomcountingPanel          matlab.ui.container.Panel
        BEC_atom_count                  matlab.ui.control.Button
        BEC_atom_counts                 matlab.ui.control.NumericEditField
        BECatomnumberEditFieldLabel     matlab.ui.control.Label
        axial_trap_freq                 matlab.ui.control.NumericEditField
        AxialtrappingfreqfHzEditFieldLabel  matlab.ui.control.Label
        radial_trap_freq                matlab.ui.control.NumericEditField
        RadialtrappingfreqfHzEditFieldLabel  matlab.ui.control.Label
        um_per_px                       matlab.ui.control.NumericEditField
        umperpxEditFieldLabel           matlab.ui.control.Label
        BEC_TOF                         matlab.ui.control.NumericEditField
        TOFmsEditFieldLabel             matlab.ui.control.Label
        BEC_diameter                    matlab.ui.control.NumericEditField
        BECdiameterpxEditFieldLabel     matlab.ui.control.Label
        ImageViewerPanel                matlab.ui.container.Panel
        Pan                             matlab.ui.control.Button
        Zoom                            matlab.ui.control.Button
        CursorButton                    matlab.ui.control.StateButton
        FrametypeEditField              matlab.ui.control.EditField
        FrametypeEditFieldLabel         matlab.ui.control.Label
        FileSelected                    matlab.ui.control.EditField
        SelectedimageLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        BrowserPanel                    matlab.ui.container.Panel
        ShownewCheckBox                 matlab.ui.control.CheckBox
        KillButton                      matlab.ui.control.Button
        ScanCheckBox                    matlab.ui.control.CheckBox
        UITable                         matlab.ui.control.Table
        ImageFolder                     matlab.ui.control.EditField
        RefreshButton                   matlab.ui.control.Button
        FolderButton                    matlab.ui.control.Button
    end


    properties (Access = private)

    end

    properties (Access = public)
        x_mark_data = [];
        y_mark_data = [];
        mark_plot = NaN;
        XMIN = NaN;
        XMAX = NaN;
        YMIN = NaN;
        YMAX = NaN;
        oldSizeX = 0;
        oldSizeY = 0;
        markplot = 0;
        mark = 0; 
        zoom_state = 0;
        pan_state = 0;
        measurement_state = 'off';
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            path = uigetdir;
            app.ImageFolder.Value = path;

            if not(isempty(path))
                % list all files with .fits extension
                file_list = dir(fullfile(app.ImageFolder.Value, '*.fits'));
                file_names = {file_list.name};
                file_names = file_names';
                file_names = flip(file_names);

                set(app.UITable,'Data',file_names);
                entries = length(file_names);
                app.UITable.ColumnName = ['FITS images: ', num2str(entries)];
            end
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % list all files with .fits extension
            file_list = dir(fullfile(app.ImageFolder.Value, '*.fits'));
            file_names = {file_list.name};
            file_names = file_names';
            file_names = flip(file_names);

            set(app.UITable,'Data',file_names);
            entries = length(file_names);
            app.UITable.ColumnName = ['FITS images: ', num2str(entries)];

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            imageList = get(app.UITable,'Data');
            app.KillButton.Enable = 'on';
            % set Selected image value:
            app.FileSelected.Value = imageList{indices};
            

            % display image
            display_image(app);

        end

        % Value changed function: FrameType
        function FrameTypeValueChanged(app, event)
            value = app.FrameType.Value;

            app.FrametypeEditField.Value = value;

            % display image
            display_image(app);

        end

        % Button pushed function: LiAtomCounting
        function LiAtomCountingPushed(app, event)

            %%%%%%%%%%%%%%%%%%%%%%%%
            % read the fits file
            img = fitsread(fullfile(app.ImageFolder.Value,app.FileSelected.Value), 'primary');

            %initialize the atom number counting parameter.
            sigma = app.CrossSection.Value;
            pixelsize = app.PixelSize.Value;
            satcount = app.SatCount.Value;
            xmin = app.XminLi.Value;
            xmax = app.XmaxLi.Value;
            ymin = app.YminLi.Value;
            ymax = app.YmaxLi.Value;

            % crop the img
            Xr=size(img(:,:,1),2);
            Yr=size(img(:,:,1),1);
            xmin=max(1,xmin);
            xmax=min(Xr,xmax);
            ymin=max(1,ymin);
            ymax=min(Yr,ymax);

            atomcount_img = img(ymin:ymax,xmin:xmax,:);
            atomnumber_map = AtomNumber(atomcount_img,pixelsize,sigma, satcount);
            Li_atomnumber = sum(sum(atomnumber_map));

            app.LiAtomNumber.Value = Li_atomnumber;


        end

        % Button pushed function: BEC_atom_count
        function BEC_atom_countPushed(app, event)
            axial=app.axial_trap_freq.Value;
            radial=app.radial_trap_freq.Value;
            umpx=app.um_per_px.Value;
            tof=app.BEC_TOF.Value/1000; % convert ms to s
            r=app.BEC_diameter.Value/2;

            hbar = 1.054e-34;
            m = 23*1.67e-27;
            omega = (2*pi*radial*2*pi*radial*2*pi*axial)^(1/3);
            a = 85 * 0.529e-10;

            BEC_cts = ((m/2*(2*pi*radial)^2/(1 + (2*pi*radial*tof)^2))...
                *(umpx*10^(-6)*r)^2)^(5/2)/(15*hbar^2*m^(1/2)*omega^3*a/2^(5/2));

            app.BEC_atom_counts.Value = BEC_cts;
        end

        % Button pushed function: ROI_Crop_Li
        function ROI_Crop_LiButtonPushed(app, event)
            [~,rect]=imcrop(app.UIAxes);
            xmin=round(rect(1));
            ymin=round(rect(2));
            xmax=round(rect(3))+xmin-1;
            ymax=round(rect(4))+ymin-1;

            app.XminLi.Value = xmin;
            app.XmaxLi.Value = xmax;
            app.YminLi.Value = ymin;
            app.YmaxLi.Value = ymax;
        end

        % Button pushed function: NaCatchDoIt
        function NaCatchDoItButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%
            % read the fits file
            img = fitsread(fullfile(app.ImageFolder.Value,app.FileSelected.Value), 'primary');

            %initialize the atom number counting parameter.
            xmin = app.Xmin_Na_Catch.Value;
            xmax = app.Xmax_Na_Catch.Value;
            ymin = app.Ymin_Na_Catch.Value;
            ymax = app.Ymax_Na_Catch.Value;


            % initialize background counting params
            xminBG = app.Xmin_Background.Value;
            xmaxBG = app.Xmax_Background.Value;
            yminBG = app.Ymin_Background.Value;
            ymaxBG = app.Ymax_Background.Value;

            % crop the img
            Xr=size(img(:,:,1),2);
            Yr=size(img(:,:,1),1);
            xmin=max(1,xmin);
            xmax=min(Xr,xmax);
            ymin=max(1,ymin);
            ymax=min(Yr,ymax);


            % crop the img for background
            Xrr=size(img(:,:,1),2);
            Yrr=size(img(:,:,1),1);
            xminBG=max(1,xminBG);
            xmaxBG=min(Xrr,xmaxBG);
            yminBG=max(1,yminBG);
            ymaxBG=min(Yrr,ymaxBG);

            % compute areas:
            BG_area = (ymaxBG-yminBG)*(xmaxBG - xminBG);
            Catch_area = (ymax-ymin)*(xmax-xmin);

            % for the mag trap
            ct_img = img(ymin:ymax,xmin:xmax,:);
            pixel_OD_number = real(-log((ct_img(:,:,1)-ct_img(:,:,3))./(ct_img(:,:,2)-ct_img(:,:,3))));

            % for the background
            bg_img = img(yminBG:ymaxBG,xminBG:xmaxBG,:);
            pixel_OD_number_BG = real(-log((bg_img(:,:,1)-bg_img(:,:,3))./(bg_img(:,:,2)-bg_img(:,:,3))));

            % turn nan and inf to 0 so that they don't count
            pixel_OD_number(isinf(pixel_OD_number)) = 0;
            pixel_OD_number(isnan(pixel_OD_number)) = 0;

            pixel_OD_number_BG(isinf(pixel_OD_number_BG)) = 0;
            pixel_OD_number_BG(isnan(pixel_OD_number_BG)) = 0;

            % take sums...
            NaCatchPxCount_WO_BG = sum(sum(pixel_OD_number));
            BG = sum(sum(pixel_OD_number_BG));

            % convert BG into total BG under catch ROI:
            BG = round(Catch_area*(BG/BG_area));
            
            % final score = catch - bg
            app.NaCatchPxCount.Value = NaCatchPxCount_WO_BG-BG;
            app.NaCatchDensity.Value = app.NaCatchPxCount.Value/Catch_area;

        end

        % Button pushed function: ROI_Crop_Na_Catch
        function ROI_Crop_Na_CatchButtonPushed(app, event)
            [~,rect]=imcrop(app.UIAxes);
            xmin=round(rect(1));
            ymin=round(rect(2));
            xmax=round(rect(3))+xmin-1;
            ymax=round(rect(4))+ymin-1;

            app.Xmin_Na_Catch.Value = xmin;
            app.Xmax_Na_Catch.Value = xmax;
            app.Ymin_Na_Catch.Value = ymin;
            app.Ymax_Na_Catch.Value = ymax;
        end

        % Value changed function: ScanCheckBox
        function ScanCheckBoxValueChanged(app, event)
            value = app.ScanCheckBox.Value;

            if value == 1
                while value == 1
                    % if checkbox is checked then basicall hit refresh every 3s
                    % then display the item #1 of the table if new entry is
                    % detected.

                    % first find how many entries there are currently:
                    table_columnName = app.UITable.ColumnName;
                    old_entries = table_columnName(14:end);

                    if isempty(old_entries)
                        % if there's nothing in the table, then do nothing
                        pause(0.5);
                    else
                        % convert to number
                        old_entries = str2num(old_entries);

                        % now refresh
                        % list all files with .fits extension
                        file_list = dir(fullfile(app.ImageFolder.Value, '*.fits'));
                        file_names = {file_list.name};
                        file_names = file_names';
                        file_names = flip(file_names);

                        set(app.UITable,'Data',file_names);
                        entries = length(file_names);
                        app.UITable.ColumnName = ['FITS images: ', num2str(entries)];

                        % turn off delete button if there is nothing to del
                        if entries == 0
                            app.KillButton.Enable = 'on';
                        end

                        % if show new is on, then show new...
                        if app.ShownewCheckBox.Value == 1
                            % make the selected image the new image
                            % which is positioned at {1}

                            if entries == old_entries + 1
                                imageList = get(app.UITable,'Data');
                                app.FileSelected.Value = imageList{1};
                                display_image(app);
                                disp('hi')
                            end
                        end

                        pause(0.5);
                    end
                    value = app.ScanCheckBox.Value;


                end
            end


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % do nothing
        end

        % Value changed function: CursorButton
        function CursorButtonValueChanged(app, event)
            value = app.CursorButton.Value;

            state = app.CursorButton.Value;
            if state == 1
                datacursormode(app.UIFigure,'on');
            else
                datacursormode(app.UIFigure,'off');
            end

        end

        % Button pushed function: MarkButton
        function MarkButtonPushed(app, event)

            app.mark = drawfreehand(app.UIAxes, 'FaceAlpha', 0);

            xy = app.mark.Position;
            app.x_mark_data = xy(:, 1);
            app.y_mark_data = xy(:, 2);

            hold(app.UIAxes, 'on'); % Don't blow away the image, then plot mark
            app.markplot = plot(app.x_mark_data, app.y_mark_data, 'LineWidth', 2,'Color','green','Parent', app.UIAxes);

            % once done, disable the button:
            app.MarkButton.Enable = 'off';
            
        end

        % Button pushed function: SavemarkButton
        function SavemarkButtonPushed(app, event)

            t = datetime('now');
            DateString = datestr(t,'yyyy-mm-dd_HH-MM-SS');
            mark_save_directory = fullfile(app.ImageFolder.Value,['mark_',DateString,'.mat']);
            x = app.x_mark_data;
            y = app.y_mark_data;
            save(mark_save_directory,'x','y');

            app.SaveMarkDir.Value = mark_save_directory;

        end

        % Button pushed function: LoadmarkButton
        function LoadmarkButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            fullpath = fullfile(path,file);
            app.LoadMarkFile.Value = fullpath;

            % read MAT file
            m = matfile(fullpath);

            app.x_mark_data = m.x;
            app.y_mark_data = m.y;

        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            
            % round value on slider to discretize
            value = app.BrightnessSlider.Value;
            
            rounded = round(value);
            app.BrightnessSlider.Value = rounded;
            
            if not(isempty(app.FileSelected.Value))
                display_image(app);
            end
            
        end

        % Size changed function: LightPanel
        function LightPanelSizeChanged(app, event)
            counting_position = app.NaBECatomcountingPanel.Position;
            
            app.LightPanel.Position = [8,counting_position(2)+158,counting_position(3),128];
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            if app.markplot ~= 0
                app.UIAxes;
                delete(app.markplot);
                app.x_mark_data = [];
                app.y_mark_data = [];
                app.markplot = 0;
                app.mark.delete();

                display_image(app);

                % after clearing, enable mark button again:
                app.MarkButton.Enable = 'on';

    
            end

        end

        % Button pushed function: Zoom
        function ZoomPushed(app, event)

            if app.zoom_state == 0
                % turning zoom on:
                app.MarkButton.Enable = 'off';
                app.CursorButton.Enable = 'off';
                app.ClearButton.Enable = 'off';
                app.FolderButton.Enable = 'off';
                app.RefreshButton.Enable = 'off';
                app.ScanCheckBox.Enable = 'off';
                app.Pan.Enable = 'off';
                app.LoadmarkButton.Enable = 'off';
                app.SavemarkButton.Enable = 'off';
                app.ROI_Crop_Li.Enable = 'off';
                app.ROI_Crop_Na_Catch.Enable = 'off';
                app.NaCatchDoIt.Enable = 'off';
                app.LiAtomCounting.Enable = 'off';
                app.BEC_atom_count.Enable = 'off';

                zoom(app.UIAxes,'on');
                app.zoom_state = 1;
                app.Zoom.Text = 'STOP';
                app.Zoom.FontColor = 'r';
            else
                % turning zoom off:
                % turning zoom on:
                app.MarkButton.Enable = 'on';
                app.CursorButton.Enable = 'on';
                app.ClearButton.Enable = 'on';
                app.FolderButton.Enable = 'on';
                app.RefreshButton.Enable = 'on';
                app.ScanCheckBox.Enable = 'on';
                app.Pan.Enable = 'on';
                app.LoadmarkButton.Enable = 'on';
                app.SavemarkButton.Enable = 'on';
                app.ROI_Crop_Li.Enable = 'on';
                app.ROI_Crop_Na_Catch.Enable = 'on';
                app.NaCatchDoIt.Enable = 'on';
                app.LiAtomCounting.Enable = 'on';
                app.BEC_atom_count.Enable = 'on';

                zoom(app.UIAxes,'off');
                app.zoom_state = 0;
                app.Zoom.Text = 'Zoom';
                app.Zoom.FontColor = 'k';


            end

        end

        % Button pushed function: Pan
        function PanButtonPushed(app, event)
            if app.pan_state == 0
                % turning pan on:
                app.MarkButton.Enable = 'off';
                app.CursorButton.Enable = 'off';
                app.ClearButton.Enable = 'off';
                app.FolderButton.Enable = 'off';
                app.RefreshButton.Enable = 'off';
                app.ScanCheckBox.Enable = 'off';
                app.Zoom.Enable = 'off';
                app.LoadmarkButton.Enable = 'off';
                app.SavemarkButton.Enable = 'off';
                app.ROI_Crop_Li.Enable = 'off';
                app.ROI_Crop_Na_Catch.Enable = 'off';
                app.NaCatchDoIt.Enable = 'off';
                app.LiAtomCounting.Enable = 'off';
                app.BEC_atom_count.Enable = 'off';

                pan(app.UIAxes,'on');
                app.pan_state = 1;
                app.Pan.Text = 'STOP';
                app.Pan.FontColor = 'r';
            else
                % turning pan off:
                % turning pan on:
                app.MarkButton.Enable = 'on';
                app.CursorButton.Enable = 'on';
                app.ClearButton.Enable = 'on';
                app.FolderButton.Enable = 'on';
                app.RefreshButton.Enable = 'on';
                app.ScanCheckBox.Enable = 'on';
                app.Zoom.Enable = 'on';
                app.LoadmarkButton.Enable = 'on';
                app.SavemarkButton.Enable = 'on';
                app.ROI_Crop_Li.Enable = 'on';
                app.ROI_Crop_Na_Catch.Enable = 'on';
                app.NaCatchDoIt.Enable = 'on';
                app.LiAtomCounting.Enable = 'on';
                app.BEC_atom_count.Enable = 'on';

                pan(app.UIAxes,'off');
                app.pan_state = 0;
                app.Pan.Text = 'Pan';
                app.Pan.FontColor = 'k';


            end
        end

        % Button pushed function: KillButton
        function KillButtonPushed(app, event)
            selected_file_name = app.FileSelected.Value;
            fullpath = fullfile(app.ImageFolder.Value,app.FileSelected.Value);
 
            if length(selected_file_name) >= 1 % if some file is selected
                delete(fullpath);
            end

            % refresh now
            file_list = dir(fullfile(app.ImageFolder.Value, '*.fits'));
            file_names = {file_list.name};
            file_names = file_names';
            file_names = flip(file_names);

            set(app.UITable,'Data',file_names);
            entries = length(file_names);
            app.UITable.ColumnName = ['FITS images: ', num2str(entries)];


        end

        % Value changed function: ShownewCheckBox
        function ShownewCheckBoxValueChanged(app, event)
            value = app.ShownewCheckBox.Value;
            
        end

        % Button pushed function: ROI_Crop_Background
        function ROI_Crop_BackgroundButtonPushed(app, event)
            [~,rect]=imcrop(app.UIAxes);
            xminBG=round(rect(1));
            yminBG=round(rect(2));
            xmaxBG=round(rect(3))+xminBG-1;
            ymaxBG=round(rect(4))+yminBG-1;

            app.Xmin_Background.Value = xminBG;
            app.Xmax_Background.Value = xmaxBG;
            app.Ymin_Background.Value = yminBG;
            app.Ymax_Background.Value = ymaxBG;   
        end

        % Callback function
        function STARTButtonValueChanged(app, event)
            value = app.STARTButton.Value;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            
            if strcmp(app.measurement_state,'off') % if START pressed
                
                % check dry run vs. custom
                if strcmp(app.SwitchFolderType.Value,'Dry run')
                    app.ImageSaverStatus.Value = 'Saving to \dryrun...';

                    % switch button status
                    app.STARTButton.Text = 'STOP';
                    app.STARTButton.FontColor = 'r';
                    app.measurement_state = 'on';

                    % disable buttons
                    app.SwitchFolderType.Enable = 'off';
                    app.CustomfoldernameLabel.Enable = 'off';
                    app.TopSideSwitch.Enable = 'off';

                    % call image_saver_script.py
                    user_entered_name = 'dryrun';
                    % redundant coding, in case of more options in the future
                    if strcmp(app.TopSideSwitch.Value,'Top')
                        image_type = 'top';
                    elseif strcmp(app.TopSideSwitch.Value,'Side')
                        image_type = 'side';
                    end
                    start_stop = '1';
                    
                    pause(1)
                    runImageSaver(user_entered_name,image_type,start_stop,app);


                else
                    if isempty(app.MeasurementName.Value)
                        app.ImageSaverStatus.Value = 'Folder name missing!';
                    else
                        % check validity of folder name
                        name_is_valid = true;

                        if ~isempty(regexp(app.MeasurementName.Value,'[^\w-_]','all'))
                            name_is_valid = false;
                        end

                        if name_is_valid % if custom folder name is not weird...
                            user_entered_name = app.MeasurementName.Value;

                            % check for duplicates
                            fullpath = mfilename('fullpath');
                            filename = [fullpath(1:end-13),'satyendra\configs\image_saver_config_local.json'];
                            fid = fopen(filename); % Opening the file
                            raw = fread(fid,inf); % Reading the contents
                            str = char(raw'); % Transformation
                            fclose(fid); % Closing the file
                            data = jsondecode(str); % Using the jsondecode function to parse JSON from string
                            save_location = data.saving_location_root_pathname;

                            current_datetime = datetime();
                            [y,m,d] = ymd(current_datetime);
                            current_year = char(string(y));
                            current_month = char(string(m));
                            if length(current_month) == 1
                                current_month = ['0',current_month];
                            end
                            current_day = char(string(d));
                            if length(current_day) == 1
                                current_day = ['0',current_day];
                            end
                            current_year_month = [current_year,'-',current_month];
                            current_year_month_day = [current_year,'-',current_month,'-',current_day];
                            new_folder_name = [save_location,'\', current_year,'\',current_year_month,'\', current_year_month_day,'\', user_entered_name];

                            if exist(new_folder_name) ~= 0
                                app.ImageSaverStatus.Value = 'Folder exists! Add to append or Retry';
                                app.AddButton.Enable = 'on';
                                % now wait for ADD button response... 
                                % If clicked then call saver
                                % else nothing happens, wait for retry

                            else % if folder name is not a duplicate...

                                % start saving --------------
                                app.ImageSaverStatus.Value = ['Saving to \',app.MeasurementName.Value,'...'];

                                % disable buttons
                                app.SwitchFolderType.Enable = 'off';
                                app.CustomfoldernameLabel.Enable = 'off';
                                app.TopSideSwitch.Enable = 'off';

                                % switch button status
                                app.STARTButton.Text = 'STOP';
                                app.STARTButton.FontColor = 'r';
                                app.measurement_state = 'on';

                                % call image_saver_script.py
                                % redundant coding, in case of more options in the future
                                if strcmp(app.TopSideSwitch.Value,'Top')
                                    image_type = 'top';
                                elseif strcmp(app.TopSideSwitch.Value,'Side')
                                    image_type = 'side';
                                end
                                start_stop = '1';
                                runImageSaver(user_entered_name,image_type,start_stop,app);
                                % ------------------------------------------------
                            end
                            
                        else
                            app.ImageSaverStatus.Value = 'Folder name not valid!';
                        end
                    end
                end


            else % if STOP pressed
                app.STARTButton.Text = 'START';
                app.STARTButton.FontColor = 'k';
                app.measurement_state = 'off';

                % re-enable buttons
                app.SwitchFolderType.Enable = 'on';
                app.CustomfoldernameLabel.Enable = 'on';
                app.TopSideSwitch.Enable = 'on';

                % reset custom folder
                app.MeasurementName.Value = '';
                app.ImageSaverStatus.Value = 'Stopped';
                
                % exit 
                start_stop = '0';
                % return to default values
                endImageSaver('dryrun', 'side',start_stop);
            end
        end

        % Callback function
        function DuplicateOKButtonPushed(app, event)

        end

        % Value changed function: AddButton
        function AddButtonValueChanged(app, event)
            value = app.AddButton.Value;

            if app.AddButton.Value == 1 % if Add Button is pressed

                % start saving --------------
                app.ImageSaverStatus.Value = ['Saving to \',app.MeasurementName.Value,'...'];

                % disable buttons
                app.SwitchFolderType.Enable = 'off';
                app.CustomfoldernameLabel.Enable = 'off';
                app.TopSideSwitch.Enable = 'off';

                % switch button status
                app.STARTButton.Text = 'STOP';
                app.STARTButton.FontColor = 'r';
                app.measurement_state = 'on';

                % call image_saver_script.py
                % redundant coding, in case of more options in the future
                if strcmp(app.TopSideSwitch.Value,'Top')
                    image_type = 'top';
                elseif strcmp(app.TopSideSwitch.Value,'Side')
                    image_type = 'side';
                end
                start_stop = '1';
                runImageSaver(app.MeasurementName.Value,image_type,start_stop,app);
                % ------------------------------------------------


                % reset Add Button
                app.AddButton.Value = 0;
                app.AddButton.Enable = "off";

            end
            
        end

        % Value changed function: SwitchFolderType
        function SwitchFolderTypeValueChanged(app, event)
            value = app.SwitchFolderType.Value;
            
            if strcmp(value,'Dry run')
                app.MeasurementName.Enable = 'off';
            else
                app.MeasurementName.Enable = 'on';
            end
        end

        % Callback function
        function StopButtonPushed(app, event)
            % if STOP pressed
            app.STARTButton.Text = 'START';
            app.STARTButton.FontColor = 'k';
            app.measurement_state = 'off';

            % re-enable buttons
            app.SwitchFolderType.Enable = 'on';
            app.CustomfoldernameLabel.Enable = 'on';
            app.TopSideSwitch.Enable = 'on';

            % reset custom folder
            app.MeasurementName.Value = '';
            app.ImageSaverStatus.Value = 'Stopped';

            % exit
            start_stop = '0';
            % return to default values
            endImageSaver('dryrun', 'side',start_stop);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowserPanel = uipanel(app.UIFigure);
            app.BrowserPanel.Title = 'Browser';
            app.FolderButton = uibutton(app.BrowserPanel, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Tag = 'folder_name';
            app.FolderButton.Text = 'Folder';
            app.RefreshButton = uibutton(app.BrowserPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ImageFolder = uieditfield(app.BrowserPanel, 'text');
            app.ImageFolder.Tag = 'path_name';
            app.UITable = uitable(app.BrowserPanel);
            app.UITable.ColumnName = {'FITS images:'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ScanCheckBox = uicheckbox(app.BrowserPanel);
            app.ScanCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScanCheckBoxValueChanged, true);
            app.ScanCheckBox.Text = ' Scan';
            app.KillButton = uibutton(app.BrowserPanel, 'push');
            app.KillButton.ButtonPushedFcn = createCallbackFcn(app, @KillButtonPushed, true);
            app.KillButton.Enable = 'off';
            app.KillButton.Text = 'Kill';
            app.ShownewCheckBox = uicheckbox(app.BrowserPanel);
            app.ShownewCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShownewCheckBoxValueChanged, true);
            app.ShownewCheckBox.Text = 'Show new';
            app.ImageViewerPanel = uipanel(app.UIFigure);
            app.ImageViewerPanel.Title = 'Image Viewer';
            app.UIAxes = uiaxes(app.ImageViewerPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SelectedimageLabel = uilabel(app.ImageViewerPanel);
            app.SelectedimageLabel.Text = 'Selected image: ';
            app.FileSelected = uieditfield(app.ImageViewerPanel, 'text');
            app.FrametypeEditFieldLabel = uilabel(app.ImageViewerPanel);
            app.FrametypeEditFieldLabel.Text = 'Frame type:';
            app.FrametypeEditField = uieditfield(app.ImageViewerPanel, 'text');
            app.FrametypeEditField.Value = 'OD';
            app.CursorButton = uibutton(app.ImageViewerPanel, 'state');
            app.CursorButton.ValueChangedFcn = createCallbackFcn(app, @CursorButtonValueChanged, true);
            app.CursorButton.Text = 'Cursor';
            app.Zoom = uibutton(app.ImageViewerPanel, 'push');
            app.Zoom.ButtonPushedFcn = createCallbackFcn(app, @ZoomPushed, true);
            app.Zoom.Text = 'Zoom';
            app.Pan = uibutton(app.ImageViewerPanel, 'push');
            app.Pan.ButtonPushedFcn = createCallbackFcn(app, @PanButtonPushed, true);
            app.Pan.Text = 'Pan';
            app.ToolsPanel = uipanel(app.UIFigure);
            app.ToolsPanel.Title = 'Tools';
            app.NaBECatomcountingPanel = uipanel(app.ToolsPanel);
            app.NaBECatomcountingPanel.Title = 'Na BEC atom counting';
            app.BECdiameterpxEditFieldLabel = uilabel(app.NaBECatomcountingPanel);
            app.BECdiameterpxEditFieldLabel.Text = 'BEC diameter (px)';
            app.BEC_diameter = uieditfield(app.NaBECatomcountingPanel, 'numeric');
            app.BEC_diameter.Value = 70;
            app.TOFmsEditFieldLabel = uilabel(app.NaBECatomcountingPanel);
            app.TOFmsEditFieldLabel.Text = 'TOF (ms)';
            app.BEC_TOF = uieditfield(app.NaBECatomcountingPanel, 'numeric');
            app.BEC_TOF.Value = 18;
            app.umperpxEditFieldLabel = uilabel(app.NaBECatomcountingPanel);
            app.umperpxEditFieldLabel.Text = 'um per px';
            app.um_per_px = uieditfield(app.NaBECatomcountingPanel, 'numeric');
            app.um_per_px.Value = 5;
            app.RadialtrappingfreqfHzEditFieldLabel = uilabel(app.NaBECatomcountingPanel);
            app.RadialtrappingfreqfHzEditFieldLabel.Text = 'Radial trapping freq (f,Hz)';
            app.radial_trap_freq = uieditfield(app.NaBECatomcountingPanel, 'numeric');
            app.radial_trap_freq.Value = 95;
            app.AxialtrappingfreqfHzEditFieldLabel = uilabel(app.NaBECatomcountingPanel);
            app.AxialtrappingfreqfHzEditFieldLabel.Text = 'Axial trapping freq (f,Hz)';
            app.axial_trap_freq = uieditfield(app.NaBECatomcountingPanel, 'numeric');
            app.axial_trap_freq.Value = 12;
            app.BECatomnumberEditFieldLabel = uilabel(app.NaBECatomcountingPanel);
            app.BECatomnumberEditFieldLabel.Text = 'BEC atom number';
            app.BEC_atom_counts = uieditfield(app.NaBECatomcountingPanel, 'numeric');
            app.BEC_atom_count = uibutton(app.NaBECatomcountingPanel, 'push');
            app.BEC_atom_count.ButtonPushedFcn = createCallbackFcn(app, @BEC_atom_countPushed, true);
            app.BEC_atom_count.Text = 'Do it!';
            app.NaCatchpixelcountingPanel = uipanel(app.ToolsPanel);
            app.NaCatchpixelcountingPanel.Title = 'Na Catch pixel counting';
            app.XRangeLabel_2 = uilabel(app.NaCatchpixelcountingPanel);
            app.XRangeLabel_2.Text = 'X Range';
            app.YRangeLabel_2 = uilabel(app.NaCatchpixelcountingPanel);
            app.YRangeLabel_2.Text = 'Y Range';
            app.Xmin_Na_Catch = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Xmin_Na_Catch.Value = 1;
            app.Xmax_Na_Catch = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Xmax_Na_Catch.Value = 512;
            app.Ymin_Na_Catch = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Ymin_Na_Catch.Value = 1;
            app.Ymax_Na_Catch = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Ymax_Na_Catch.Value = 512;
            app.ROI_Crop_Na_Catch = uibutton(app.NaCatchpixelcountingPanel, 'push');
            app.ROI_Crop_Na_Catch.ButtonPushedFcn = createCallbackFcn(app, @ROI_Crop_Na_CatchButtonPushed, true);
            app.ROI_Crop_Na_Catch.Text = 'ROI Crop';
            app.NaCatchDoIt = uibutton(app.NaCatchpixelcountingPanel, 'push');
            app.NaCatchDoIt.ButtonPushedFcn = createCallbackFcn(app, @NaCatchDoItButtonPushed, true);
            app.NaCatchDoIt.Text = 'Do it!';
            app.CatchscoreLabel = uilabel(app.NaCatchpixelcountingPanel);
            app.CatchscoreLabel.Text = 'Catch score';
            app.NaCatchPxCount = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.XRangeLabel_3 = uilabel(app.NaCatchpixelcountingPanel);
            app.XRangeLabel_3.Text = 'X Range';
            app.YRangeLabel_3 = uilabel(app.NaCatchpixelcountingPanel);
            app.YRangeLabel_3.Text = 'Y Range';
            app.Xmin_Background = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Xmin_Background.Value = 1;
            app.Xmax_Background = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Xmax_Background.Value = 512;
            app.Ymin_Background = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Ymin_Background.Value = 1;
            app.Ymax_Background = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.Ymax_Background.Value = 512;
            app.ROI_Crop_Background = uibutton(app.NaCatchpixelcountingPanel, 'push');
            app.ROI_Crop_Background.ButtonPushedFcn = createCallbackFcn(app, @ROI_Crop_BackgroundButtonPushed, true);
            app.ROI_Crop_Background.Text = 'ROI Crop';
            app.BackgroundROILabel = uilabel(app.NaCatchpixelcountingPanel);
            app.BackgroundROILabel.Text = 'Background ROI:';
            app.CatchROILabel = uilabel(app.NaCatchpixelcountingPanel);
            app.CatchROILabel.Text = 'Catch ROI:';
            app.CatchscoreLabel_2 = uilabel(app.NaCatchpixelcountingPanel);
            app.CatchscoreLabel_2.Text = 'Density';
            app.NaCatchDensity = uieditfield(app.NaCatchpixelcountingPanel, 'numeric');
            app.LiLFatomcountingPanel = uipanel(app.ToolsPanel);
            app.LiLFatomcountingPanel.Title = 'Li LF atom counting';
            app.XRangeLabel = uilabel(app.LiLFatomcountingPanel);
            app.XRangeLabel.Text = 'X Range';
            app.YRangeLabel = uilabel(app.LiLFatomcountingPanel);
            app.YRangeLabel.Text = 'Y Range';
            app.XminLi = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.XminLi.Value = 1;
            app.XmaxLi = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.XmaxLi.Value = 512;
            app.YminLi = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.YminLi.Value = 1;
            app.YmaxLi = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.YmaxLi.Value = 512;
            app.ROI_Crop_Li = uibutton(app.LiLFatomcountingPanel, 'push');
            app.ROI_Crop_Li.ButtonPushedFcn = createCallbackFcn(app, @ROI_Crop_LiButtonPushed, true);
            app.ROI_Crop_Li.Text = 'ROI Crop';
            app.PixelSizeum2EditFieldLabel = uilabel(app.LiLFatomcountingPanel);
            app.PixelSizeum2EditFieldLabel.Text = 'Pixel Size (um^2)';
            app.PixelSize = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.PixelSize.Value = 27.52;
            app.CrossSectionum2EditFieldLabel = uilabel(app.LiLFatomcountingPanel);
            app.CrossSectionum2EditFieldLabel.Text = 'Cross Section (um^2)';
            app.CrossSection = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.CrossSection.Value = 0.215;
            app.SaturationCountsEditFieldLabel = uilabel(app.LiLFatomcountingPanel);
            app.SaturationCountsEditFieldLabel.Text = 'Saturation Counts';
            app.SatCount = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.SatCount.Value = 133570;
            app.AtomnumberEditFieldLabel = uilabel(app.LiLFatomcountingPanel);
            app.AtomnumberEditFieldLabel.Text = 'Atom number';
            app.LiAtomNumber = uieditfield(app.LiLFatomcountingPanel, 'numeric');
            app.LiAtomCounting = uibutton(app.LiLFatomcountingPanel, 'push');
            app.LiAtomCounting.ButtonPushedFcn = createCallbackFcn(app, @LiAtomCountingPushed, true);
            app.LiAtomCounting.Text = 'Do it!';
            app.MarkingToolPanel = uipanel(app.ToolsPanel);
            app.MarkingToolPanel.Title = 'Marking Tool';
            app.MarkButton = uibutton(app.MarkingToolPanel, 'push');
            app.MarkButton.ButtonPushedFcn = createCallbackFcn(app, @MarkButtonPushed, true);
            app.MarkButton.Text = 'Mark';
            app.LoadmarkButton = uibutton(app.MarkingToolPanel, 'push');
            app.LoadmarkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmarkButtonPushed, true);
            app.LoadmarkButton.Text = 'Load mark';
            app.LoadMarkFile = uieditfield(app.MarkingToolPanel, 'text');
            app.SavemarkButton = uibutton(app.MarkingToolPanel, 'push');
            app.SavemarkButton.ButtonPushedFcn = createCallbackFcn(app, @SavemarkButtonPushed, true);
            app.SavemarkButton.Text = 'Save mark';
            app.SaveMarkDir = uieditfield(app.MarkingToolPanel, 'text');
            app.ShowCheckBox = uicheckbox(app.MarkingToolPanel);
            app.ShowCheckBox.Text = 'Show';
            app.ShowCheckBox.Value = true;
            app.ClearButton = uibutton(app.MarkingToolPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.LightPanel = uipanel(app.ToolsPanel);
            app.LightPanel.Title = 'Light';
            app.LightPanel.SizeChangedFcn = createCallbackFcn(app, @LightPanelSizeChanged, true);
            app.FrameType = uilistbox(app.LightPanel);
            app.FrameType.Items = {'OD', 'With atoms', 'Without atoms', 'Dark', 'FakeOD'};
            app.FrameType.ValueChangedFcn = createCallbackFcn(app, @FrameTypeValueChanged, true);
            app.FrameType.Value = 'OD';
            app.MinEditField = uieditfield(app.LightPanel, 'numeric');
            app.MinEditFieldLabel = uilabel(app.LightPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MaxEditFieldLabel = uilabel(app.LightPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.LightPanel, 'numeric');
            app.MaxEditField.Value = 1.3;
            app.BrightnessSliderLabel = uilabel(app.LightPanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.LightPanel);
            app.BrightnessSlider.Limits = [10 16];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [9 11 13 15];
            app.BrightnessSlider.Value = 14;
            app.MeasurementPanel = uipanel(app.UIFigure);
            app.MeasurementPanel.Title = 'Measurement';
            app.SwitchFolderType = uiswitch(app.MeasurementPanel, 'slider');
            app.SwitchFolderType.Items = {'Dry run', 'Custom'};
            app.SwitchFolderType.ValueChangedFcn = createCallbackFcn(app, @SwitchFolderTypeValueChanged, true);
            app.SwitchFolderType.Value = 'Dry run';
            app.CustomfoldernameLabel = uilabel(app.MeasurementPanel);
            app.CustomfoldernameLabel.Text = 'Custom folder name:';
            app.MeasurementName = uieditfield(app.MeasurementPanel, 'text');
            app.MeasurementName.Enable = 'off';
            app.TopSideSwitch = uiswitch(app.MeasurementPanel, 'slider');
            app.TopSideSwitch.Items = {'Top', 'Side'};
            app.TopSideSwitch.Value = 'Side';
            app.ImagesaverstatusLabel = uilabel(app.MeasurementPanel);
            app.ImagesaverstatusLabel.Text = 'Image saver status:';
            app.ImageSaverStatus = uieditfield(app.MeasurementPanel, 'text');
            app.STARTButton = uibutton(app.MeasurementPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.AddButton = uibutton(app.MeasurementPanel, 'state');
            app.AddButton.ValueChangedFcn = createCallbackFcn(app, @AddButtonValueChanged, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Browser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end